classdef ClassifierTrainer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        axes1                           matlab.ui.control.UIAxes
        uipanel_classification          matlab.ui.container.Panel
        text_classification             matlab.ui.control.Label
        pushbutton_cancelclassification  matlab.ui.control.Button
        pushbutton_addall               matlab.ui.control.Button
        uipanel_categories              matlab.ui.container.Panel
        pushbutton_addcategory          matlab.ui.control.Button
        pushbutton_deletecategory       matlab.ui.control.Button
        listbox_categories              matlab.ui.control.ListBox
        pushbutton_renamecategory       matlab.ui.control.Button
        uipanel_general                 matlab.ui.container.Panel
        pushbutton_newclassifier        matlab.ui.control.Button
        pushbutton_loadclassifier       matlab.ui.control.Button
        pushbutton_saveclassifier       matlab.ui.control.Button
        pushbutton_startclassification  matlab.ui.control.Button
        text_noclassify                 matlab.ui.control.Label
        edit_noclassify                 matlab.ui.control.EditField
        text_mintrackpoints             matlab.ui.control.Label
        edit_mintrackpoints             matlab.ui.control.EditField
        pushbutton_category1            matlab.ui.control.Button
        pushbutton_category2            matlab.ui.control.Button
        pushbutton_category3            matlab.ui.control.Button
        pushbutton_category4            matlab.ui.control.Button
        pushbutton_plotTrainingset      matlab.ui.control.Button
        pushbutton_comptree             matlab.ui.control.Button
        text_maxnumsplits               matlab.ui.control.Label
        edit_maxnumsplits               matlab.ui.control.EditField
        pushbutton_plottree             matlab.ui.control.Button
        pushbutton_segment              matlab.ui.control.Button
        pushbutton_nosegmentation       matlab.ui.control.Button
        text_category1                  matlab.ui.control.Label
        text_category2                  matlab.ui.control.Label
        text_category3                  matlab.ui.control.Label
        text_category4                  matlab.ui.control.Label
        pushbutton_back                 matlab.ui.control.Button
        checkbox_scaleauto              matlab.ui.control.CheckBox
        text_plotrange                  matlab.ui.control.Label
        edit_plotrange                  matlab.ui.control.EditField
        text_hotkeys                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        parent % Description
        debugFlag = false; % debug mode
        trainingset % the training set of classified tracks
        trainingpool % pool from which the to-be-classified tracks are drawn
    end
    
    methods (Access = private)
        function errorBox(app, err)
            % if there is an error invoked, we output it in a dialog box
            if app.debugFlag
                errordlg(getReport(err,'extended','hyperlinks','off'),'Error');
            else
                errordlg(getReport(err,'basic','hyperlinks','off'),'Error');
            end
        end
        
        function finishedClassification(app)
            
            app.pushbutton_startclassification.UserData = [];
            
            cla(app.axes1)
            
            TrackC = app.pushbutton_newclassifier.UserData;
            Track = app.pushbutton_loadclassifier.UserData;
            sampleIDX = app.text_mintrackpoints.UserData;
            nManualClassifications = app.edit_mintrackpoints.UserData;
            sampleIDX = sampleIDX(1:nManualClassifications);
            categorization = app.text_classification.UserData;
            
            Track = Track.resetUnitSystem; % set back to SI
            
            % txt = cell(numel(TrackC),1);
            for ii = 1:numel(TrackC)
                take = categorization == ii;
                coords = Track.coords(sampleIDX(take));
                time = Track.time(sampleIDX(take));
                % --- convert to SI
                uFc = Track.getUnitFactor('pixelsize');
                uFt = Track.getUnitFactor('dt');
                coords = cellfun(@(x) x.*uFc,coords,'UniformOutput',false);
                time = cellfun(@(x) x.*uFt,time,'UniformOutput',false);
                % --- add
                TrackC(ii) = TrackC(ii).addTrack(time,coords);
            end
            
            % have to recompute properties, due to addition new tracks.
            wb = waitbar(0,'Computing center','Name','Computing all track properties');
            TrackC = TrackC.computeCenter;
            waitbar(0.2,wb,'Computing entropy')
            TrackC = TrackC.computeEntropy;
            waitbar(0.4,wb,'Computing PCA')
            TrackC = TrackC.computePCA;
            waitbar(0.6,wb,'Computing MSD')
            TrackC = TrackC.computeMSD;
            % waitbar(0.8,wb,'Computing D')
            % TrackC = TrackC.setArrayProperty('Dest',Dest.MSD);
            % TrackC = TrackC.computeDiffusion;
            close(wb)
            
            app.pushbutton_newclassifier.UserData = TrackC;
            
            app.updateText('Finished classification')
            
            app.pushbutton_plotTrainingset_Callback([]) % plot track
        end
        
        function plotClassification(app)
            
            idx = app.pushbutton_startclassification.UserData;
            nManualClassifications = app.edit_mintrackpoints.UserData;
            
            cla(app.axes1);
            
            % if we're done with all classifications, stop
            if idx > nManualClassifications
                app.finishedClassification
                return
            end
            
            % else
            
            % set text
            txt = [num2str(idx) '/' num2str(nManualClassifications)];
            app.text_classification.String = {'Classification in progress:',txt};
            
            % plot
            
            Track = app.pushbutton_loadclassifier.UserData;
            sampleIDX = app.text_mintrackpoints.UserData;
            index = sampleIDX(idx);
            [unitFactor,unitLabel] = Track.getUnitFactor('pixelsize');
            track = Track.coords{index};
            
            x = track(:,1).*unitFactor;
            y = track(:,2).*unitFactor;
            
            plot(app.axes1, x, y, '-x', ...
                'LineWidth',1.5);


            MBCC = Track.MinBoundCircleCenter(sampleIDX(idx),:).*unitFactor;
            MBCR = Track.MinBoundCircleRadius(sampleIDX(idx)).*unitFactor;
            CoM = Track.CenterOfMass(sampleIDX(idx),:).*unitFactor;
            
            plotrange_m = str2double(app.edit_plotrange.String); % use userspecified range
            if ~app.checkbox_scaleauto.Value && ~isnan(plotrange_m)
                addLim = plotrange_m/2;
            else % do auto based on MBCR
                addLim = MBCR*1.1;
            end
            xln = [MBCC(1)-addLim MBCC(1)+addLim];
            yln = [MBCC(2)-addLim MBCC(2)+addLim];
            xlim(xln);
            ylim(yln);
            
            hold on;
            plot(app.axes1,MBCC(1),MBCC(2),'ro');
            plot(app.axes1,CoM(1),CoM(2),'go');
            hold off;
            
            xlabel(['x (' unitLabel ')'])
            ylabel(['y (' unitLabel ')'])
            axis equal
        end
        
        function setClassification(app, nCat)
            
            idx = app.pushbutton_startclassification.UserData;
            app.text_classification.UserData(idx) = nCat;
            
            idx = idx + 1;
            app.pushbutton_startclassification.UserData = idx;


            app.plotClassification
        end
        
        function updateCategories(app)
            
            TrackC = app.pushbutton_newclassifier.UserData;
            
            numCat = numel(TrackC);
            catNames = {TrackC.name};
            
            app.listbox_categories.String = catNames; % set names of categories
            
            for ii = 1:4
                thisName = ['pushbutton_category' num2str(ii)];
                thisName2 = ['text_category' num2str(ii)];
                if ii > numCat
                    app.(thisName).Visible = 'off';
                    app.(thisName2).Visible = 'off';
                else
                    app.(thisName).Visible = 'on';
                    app.(thisName2).Visible = 'on';
                    app.(thisName).String = catNames{ii};
                end
            
            end
        end
        
        function updateText(app, msg)
            
            TrackC = app.pushbutton_newclassifier.UserData;
            
            nTracks = [TrackC.nTracks];
            
            txt = cell(numel(TrackC),1);
            for ii = 1:numel(TrackC)
                txt{ii} = [TrackC(ii).name ': ' num2str(nTracks(ii))];
            end
            
            txt2 = sprintf('Trainings set contains %i tracks.',sum([TrackC.nTracks]));
            
            app.text_classification.String = [{msg},...
                {txt2},txt{:}];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ClassifierTrainer_OpeningFcn(app, parent, Tracks)

            % store parent
            app.parent = parent;
            
            % store training pool
            if numel(Track) ~= 1
                error('Number of parsed tracks should be one.')
            end
            app.trainingpool = Tracks;


        end

        % Value changed function: checkbox_scaleauto
        function checkbox_scaleauto_Callback(app, event)
            app.plotClassification
        end

        % Value changed function: edit_plotrange
        function edit_plotrange_Callback(app, event)
            app.plotClassification
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)

            try
            
%                 if isequal(get(hObject, 'waitstatus'), 'waiting')
%                     % The GUI is still in UIWAIT, us UIRESUME
%                     uiresume(hObject);
%                 else
%                     % The GUI is no longer waiting, just close it
%                     delete(hObject);
%                 end
                delete(app)
            
            catch err
                app.errorBox(err)
            end
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)

            switch eventdata.Character
                case 'z'
                    app.pushbutton_category1_Callback([])
                case 'x'
                    app.pushbutton_category2_Callback([])
                case 'c'
                    app.pushbutton_category3_Callback([])
                case 'v'
                    app.pushbutton_category4_Callback([])
                otherwise
            
            end
        end

        % Button pushed function: pushbutton_addall
        function pushbutton_addall_Callback(app, event)

            app.text_classification.UserData(:) = app.listbox_categories.Value; % set all to selected category
            app.finishedClassification
        end

        % Button pushed function: pushbutton_addcategory
        function pushbutton_addcategory_Callback(app, event)

            try
                TrackC = app.pushbutton_newclassifier.UserData;
            
                if ~isempty(TrackC)
                    numCat = numel(TrackC);
            
                    if numCat >= 4
                        error('Maximum 4 categories are allowed.')
                    end
            
                    TrackC = [TrackC tracks()]; % units pixelsize and dt are initialised to SI by default
                    TrackC(end).name = ['Category ' num2str(numCat+1)];
            
                    app.pushbutton_newclassifier.UserData = TrackC;
            
                    app.updateCategories
                    app.updateText('Added category')
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_back
        function pushbutton_back_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                if isempty(idx)
                    return
                end
            
                if app.pushbutton_startclassification.UserData > 1
                    app.pushbutton_startclassification.UserData = app.pushbutton_startclassification.UserData - 1; % go back
                end
            
                app.plotClassification
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_cancelclassification
        function pushbutton_cancelclassification_Callback(app, event)

            try
            
                classifactionIDX = app.pushbutton_startclassification.UserData;
            
                if ~isempty(classifactionIDX)
            
                    app.pushbutton_startclassification.UserData = []; % only variable checked to mark progress classification
            
                    app.edit_mintrackpoints.UserData = []; % is not checked, doesnt have to be reset
                    app.text_classification.UserData = []; % is not checked, doesnt have to be reset
            
                    app.updateText('Cancelled classification')
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category1
        function pushbutton_category1_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                if isempty(idx)
                    return
                end
            
                app.setClassification(1)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category2
        function pushbutton_category2_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                numCat = numel(app.pushbutton_newclassifier.UserData);
                if isempty(idx) || numCat < 2
                    return
                end
            
                app.setClassification(2)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category3
        function pushbutton_category3_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                numCat = numel(app.pushbutton_newclassifier.UserData);
                if isempty(idx) || numCat < 3
                    return
                end
            
                app.setClassification(3)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category4
        function pushbutton_category4_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                numCat = numel(app.pushbutton_newclassifier.UserData);
                if isempty(idx) || numCat < 4
                    return
                end
            
                app.setClassification(4)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_comptree
        function pushbutton_comptree_Callback(app, event)

            try
            
                TrackC = app.pushbutton_newclassifier.UserData;
            
                if ~isempty(TrackC)
                    maxnumsplits = str2double(app.edit_maxnumsplits.String);
                    if ~isnan(maxnumsplits)
            
                        wb = waitbar(0,'Preparing data','Name','Computing all decision tree');
            
                        TrackProps_wanted = app.pushbutton_saveclassifier.UserData.popupmenu_TrackProp1.UserData; % from diffusionlab
                        % name, location in obj.(location), unit for unitFactor, column of data
            
                        Track = app.pushbutton_loadclassifier.UserData;
            
                        take = zeros(size(TrackProps_wanted,1),1);
                        for ii = 1:size(TrackProps_wanted,1)
                            try
                                prop = Track.getProp(TrackProps_wanted{ii,2},true);
                                if ~isempty(prop) && size(prop,2) >= TrackProps_wanted{ii,4}
                                    take(ii) = 1;
                                end
                            catch
                                % do nothing
                            end
                        end
            
                        TrackProps = TrackProps_wanted(find(take),:);
                        TrackProps = TrackProps(~strncmpi(TrackProps(:,2),'Dest',4),:); % throw out diffusion constant
            %             TrackProps = TrackProps(~strcmp(TrackProps(:,1),'Localization variance'),:); % throw out diffusion constant
            %             TrackProps = TrackProps(~strcmp(TrackProps(:,1),'Diffusion SNR'),:); % throw out diffusion constant
            
            %             if isempty(TrackProps)
            %                 close(wb)
            %                 error('No track properties computed for tracks.')
            %             end
            
            
                        nTracks = [TrackC.nTracks];
                        nProps = size(TrackProps,1);
            
            
                        X = nan(sum(nTracks),nProps);
            
                        for ii = 1:size(TrackProps,1) % loop over categories
                            data = vertcat(TrackC.(TrackProps{ii,2})); % check extraction from cell array
                            data = data(:,TrackProps{ii,4});
                            X(:,ii) = data;
                        end
            
                        Y = cell(sum(nTracks),1);
                        c = 1;
                        for ii = 1:numel(TrackC)
            
                            Y(c:c+nTracks(ii)-1) = {TrackC(ii).name};
                            c = c + nTracks(ii);
            
                        end
            
                        waitbar(0.5,wb,'Computing decision tree')
            
                        ctree = fitctree(X,Y,'MaxNumSplits',maxnumsplits,'PredictorNames',TrackProps(:,1)); % create classification tree
            
                        view(ctree,'mode','graph');
            
                        app.pushbutton_comptree.UserData = ctree;
                        app.pushbutton_segment.UserData = TrackProps;
            
                        close(wb)
            
                    end
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_deletecategory
        function pushbutton_deletecategory_Callback(app, event)

            try
                TrackC = app.pushbutton_newclassifier.UserData;
            
                if ~isempty(TrackC)
            
                    numCat = numel(TrackC);
            
                    if numCat == 1
                        error('Minimum of 1 categories is required.')
                    end
            
                    thisCat = app.listbox_categories.Value;
                    TrackC(thisCat) = [];
                    app.listbox_categories.Value = 1;
            
                    app.pushbutton_newclassifier.UserData = TrackC;
            
                    app.updateCategories
                    app.updateText('Deleted category')
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_loadclassifier
        function pushbutton_loadclassifier_Callback(app, event)
            
            try
                % --- load Track
                [fname,pname,idx] =uigetfile( ...
                    {'*.mat','MATLAB Files (*.mat)'},...
                   'Please select the file containing the track classifier');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        in = load(fullfile(pname,fname)); % Loads 'Track'
                        fn = fieldnames(in);
                        nTracksObj = 0;
                        for ii = 1:numel(fn)
                            if isa(in.(fn{ii}),'tracks')
                                TrackC = in.(fn{ii});
                                nTracksObj = nTracksObj + 1;
                            end
                        end
                        if nTracksObj ~= 1
                            error('Expected one ''tracks'' object but found %d instead.',...
                                nTracksObj);
                        end
                        app.pushbutton_newclassifier.UserData = TrackC;
                end
            
                app.updateCategories
                app.updateText('Loaded classifier')
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_newclassifier
        function pushbutton_newclassifier_Callback(app, event)
            
            % The classifier is a tracks object array, in which each population is a
            % classified category. In this way, the classifier can be easily plotted
            % and trainings data can be added. However, to allow for extension, the
            % units should be the same, hence, the classifier is always defined in SI
            % units.
            
            try
                TrackC = tracks(); % units pixelsize and dt are initialised to SI by default. Thus, first convert added tracks to SI units.
                TrackC.name = 'Category 1';
                TrackC.dt = 1;
                TrackC.dte = 0;
            
                app.pushbutton_newclassifier.UserData = TrackC;
            
                app.updateCategories
                app.updateText('Started new classifier')
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_nosegmentation
        function pushbutton_nosegmentation_Callback(app, event)
            
            try
                Track = app.pushbutton_loadclassifier.UserData; % give original data back
                app.pushbutton_nosegmentation.UserData = Track;
                app.pushbutton_nosegmentation.UserData = [];
                app.figure1_CloseRequestFcn([])
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_plotTrainingset
        function pushbutton_plotTrainingset_Callback(app, event)

            try
            
                TrackC = app.pushbutton_newclassifier.UserData;
            
                if ~isempty(TrackC)
            
                    if any([TrackC.nTracks] == 0)
            
                        nTracks = [TrackC.nTracks];
                        idx = nTracks == 0; % cannot plot empty tracks
            
                        TrackC(idx) = [];
            
                    end
            
                    figure
                    hps = TrackC.plotTracks;
            
                    % manually set legend
                    nCat = numel(TrackC);
                    legendTxt = cell(nCat,1);
                    legendN = cumsum([TrackC.nTracks]);
                    legendHps = zeros(nCat,1);
                    for ii = 1:nCat
                        legendTxt{ii} = TrackC(ii).name;
                        legendHps(ii) = hps(legendN(ii));
                    end
                    legend(legendHps,legendTxt{:})
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_plottree
        function pushbutton_plottree_Callback(app, event)

            try
                ctree = app.pushbutton_comptree.UserData;
            
                if ~isempty(ctree)
                    view(ctree,'mode','graph');
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_renamecategory
        function pushbutton_renamecategory_Callback(app, event)

            try
            
                TrackC = app.pushbutton_newclassifier.UserData;
            
                if ~isempty(TrackC)
            
                    thisCat = app.listbox_categories.Value;
            
                    prompt = {'Enter category name:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {TrackC(thisCat).name};
                    newName = inputdlg(prompt,dlgtitle,dims,definput);
                    TrackC(thisCat).name = newName{1};
            
                    app.pushbutton_newclassifier.UserData = TrackC;
            
                    app.updateCategories
                    app.updateText('Renamed category')
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_saveclassifier
        function pushbutton_saveclassifier_Callback(app, event)

            try
                TrackC = app.pushbutton_newclassifier.UserData;
                if ~isempty(TrackC)
                    [fname,pname] = uiputfile('.mat','Save tracks classifier');
                    wb = waitbar(0.5,'Saving classifier...','Name','Please wait');
                    save(fullfile(pname,fname),'TrackC','-v7.3');
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_segment
        function pushbutton_segment_Callback(app, event)

            try
                ctree = app.pushbutton_comptree.UserData;
                if isempty(ctree)
                    error('Please compute decision tree first.')
                end
            
                TrackProps = app.pushbutton_segment.UserData;
%                 TrackC = app.pushbutton_newclassifier.UserData;
                Track = app.pushbutton_loadclassifier.UserData;
            
                X = nan(Track.nTracks,size(TrackProps,1));
            
                for ii = 1:size(TrackProps,1) % loop over categories
                    data = vertcat(Track.(TrackProps{ii,2})); % check extraction from cell array
                    data = data(:,TrackProps{ii,4});
                    data = data.*Track.getUnitFactor(TrackProps{ii,3}); % data is Track is not in SI units! Only our classifier is!
                    X(:,ii) = data;
                end
            
                TrackClass = predict(ctree,X);
            
                uniqueClass = unique(TrackClass);
                nClass = numel(uniqueClass);
            
                Track_out = tracks.empty(nClass,0);
            
                for ii = 1:nClass
            %         take = strcmp(TrackClass,uniqueClass(ii));
                    Track_out(ii,1) = Track.segment(strcmp(TrackClass,uniqueClass{ii}));
                end
            
            %     classNames = {TrackC.name};
                popNr = app.uipanel_general.UserData;
            
                Track_out(1).segTree = Track_out(1).segTree.add(popNr,uniqueClass{:});
                Track_out = Track_out.setArrayProperty('segTree',Track_out(1).segTree);
            
                app.pushbutton_nosegmentation.UserData = Track_out;
            
                app.figure1_CloseRequestFcn([])
            
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_startclassification
        function pushbutton_startclassification_Callback(app, event)

            try
                TrackC = app.pushbutton_newclassifier.UserData;
                if ~isempty(TrackC)
                    Track = app.pushbutton_loadclassifier.UserData;
                    nManualClassifications = str2double(get(app.edit_noclassify,'String'));
                    if ~isnan(nManualClassifications)
                        doneIDX = app.text_mintrackpoints.UserData;
            
                        tooShortIDX = Track.nTrackPoints < str2double(get(app.edit_mintrackpoints,'String'));
                        tooShortIDX(doneIDX) = true;
                        goodIDX = find(~tooShortIDX);
            
                        if numel(goodIDX) >= nManualClassifications
                            sampleIDX = randsample(goodIDX,nManualClassifications);
                            app.text_mintrackpoints.UserData = vertcat(sampleIDX,doneIDX); % store the done idx without classifying them, because numel(sampleIDX) == nClassifications
                            app.pushbutton_startclassification.UserData = 1; % start at one
                            app.edit_mintrackpoints.UserData = nManualClassifications;
                            app.text_classification.UserData = nan(nManualClassifications,1);
                            app.plotClassification;
                        else
                            error('The number of remaining tracks (%i) is smaller than the training set (%i).',numel(goodIDX),nManualClassifications);
                        end
                    end
                end
            catch err
                app.errorBox(err)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ClassifierTrainer';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.axes1 = uiaxes(app.figure1);
            app.uipanel_classification = uipanel(app.figure1);
            app.uipanel_classification.Title = 'Classification';
            app.text_classification = uilabel(app.uipanel_classification);
            app.text_classification.Text = 'No classification started';
            app.pushbutton_cancelclassification = uibutton(app.uipanel_classification, 'push');
            app.pushbutton_cancelclassification.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancelclassification_Callback, true);
            app.pushbutton_cancelclassification.Text = 'Cancel classification';
            app.pushbutton_addall = uibutton(app.uipanel_classification, 'push');
            app.pushbutton_addall.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_addall_Callback, true);
            app.pushbutton_addall.Text = 'Add all tracks to current category';
            app.uipanel_categories = uipanel(app.figure1);
            app.uipanel_categories.Title = 'Categories';
            app.pushbutton_addcategory = uibutton(app.uipanel_categories, 'push');
            app.pushbutton_addcategory.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_addcategory_Callback, true);
            app.pushbutton_addcategory.Text = 'Add category';
            app.pushbutton_deletecategory = uibutton(app.uipanel_categories, 'push');
            app.pushbutton_deletecategory.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_deletecategory_Callback, true);
            app.pushbutton_deletecategory.Text = 'Delete category';
            app.listbox_categories = uilistbox(app.uipanel_categories);
            app.listbox_categories.Items = {'No classifier loaded'};
            app.listbox_categories.Value = 'No classifier loaded';
            app.pushbutton_renamecategory = uibutton(app.uipanel_categories, 'push');
            app.pushbutton_renamecategory.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_renamecategory_Callback, true);
            app.pushbutton_renamecategory.Text = 'Rename category';
            app.uipanel_general = uipanel(app.figure1);
            app.uipanel_general.Title = 'General';
            app.pushbutton_newclassifier = uibutton(app.uipanel_general, 'push');
            app.pushbutton_newclassifier.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_newclassifier_Callback, true);
            app.pushbutton_newclassifier.Text = 'New classifier';
            app.pushbutton_loadclassifier = uibutton(app.uipanel_general, 'push');
            app.pushbutton_loadclassifier.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadclassifier_Callback, true);
            app.pushbutton_loadclassifier.Text = 'Load classifier';
            app.pushbutton_saveclassifier = uibutton(app.uipanel_general, 'push');
            app.pushbutton_saveclassifier.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveclassifier_Callback, true);
            app.pushbutton_saveclassifier.Text = 'Save classifier as';
            app.pushbutton_startclassification = uibutton(app.figure1, 'push');
            app.pushbutton_startclassification.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_startclassification_Callback, true);
            app.pushbutton_startclassification.Text = 'Start classification';
            app.text_noclassify = uilabel(app.figure1);
            app.text_noclassify.Text = 'No. tracks to classify';
            app.edit_noclassify = uieditfield(app.figure1, 'text');
            app.edit_noclassify.Value = '100';
            app.text_mintrackpoints = uilabel(app.figure1);
            app.text_mintrackpoints.Text = 'Minimum track points';
            app.edit_mintrackpoints = uieditfield(app.figure1, 'text');
            app.edit_mintrackpoints.Value = '10';
            app.pushbutton_category1 = uibutton(app.figure1, 'push');
            app.pushbutton_category1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category1_Callback, true);
            app.pushbutton_category1.Visible = 'off';
            app.pushbutton_category1.Text = 'Category 1';
            app.pushbutton_category2 = uibutton(app.figure1, 'push');
            app.pushbutton_category2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category2_Callback, true);
            app.pushbutton_category2.Visible = 'off';
            app.pushbutton_category2.Text = 'Category 2';
            app.pushbutton_category3 = uibutton(app.figure1, 'push');
            app.pushbutton_category3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category3_Callback, true);
            app.pushbutton_category3.Visible = 'off';
            app.pushbutton_category3.Text = 'Category 3';
            app.pushbutton_category4 = uibutton(app.figure1, 'push');
            app.pushbutton_category4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category4_Callback, true);
            app.pushbutton_category4.Visible = 'off';
            app.pushbutton_category4.Text = 'Category 4';
            app.pushbutton_plotTrainingset = uibutton(app.figure1, 'push');
            app.pushbutton_plotTrainingset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotTrainingset_Callback, true);
            app.pushbutton_plotTrainingset.Text = 'Plot trainings set';
            app.pushbutton_comptree = uibutton(app.figure1, 'push');
            app.pushbutton_comptree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_comptree_Callback, true);
            app.pushbutton_comptree.Text = 'Compute decision tree';
            app.text_maxnumsplits = uilabel(app.figure1);
            app.text_maxnumsplits.Text = 'Maximum num. spilts';
            app.edit_maxnumsplits = uieditfield(app.figure1, 'text');
            app.edit_maxnumsplits.Value = '10';
            app.pushbutton_plottree = uibutton(app.figure1, 'push');
            app.pushbutton_plottree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plottree_Callback, true);
            app.pushbutton_plottree.Text = 'Plot decision tree';
            app.pushbutton_segment = uibutton(app.figure1, 'push');
            app.pushbutton_segment.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_segment_Callback, true);
            app.pushbutton_segment.Text = 'Segment and close trainer';
            app.pushbutton_nosegmentation = uibutton(app.figure1, 'push');
            app.pushbutton_nosegmentation.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nosegmentation_Callback, true);
            app.pushbutton_nosegmentation.Text = 'Close trainer (no segmentation)';
            app.text_category1 = uilabel(app.figure1);
            app.text_category1.Visible = 'off';
            app.text_category1.Text = 'z';
            app.text_category2 = uilabel(app.figure1);
            app.text_category2.Visible = 'off';
            app.text_category2.Text = 'x';
            app.text_category3 = uilabel(app.figure1);
            app.text_category3.Visible = 'off';
            app.text_category3.Text = 'c';
            app.text_category4 = uilabel(app.figure1);
            app.text_category4.Visible = 'off';
            app.text_category4.Text = 'v';
            app.pushbutton_back = uibutton(app.figure1, 'push');
            app.pushbutton_back.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_back_Callback, true);
            app.pushbutton_back.Text = 'Go back';
            app.checkbox_scaleauto = uicheckbox(app.figure1);
            app.checkbox_scaleauto.ValueChangedFcn = createCallbackFcn(app, @checkbox_scaleauto_Callback, true);
            app.checkbox_scaleauto.Text = 'auto';
            app.checkbox_scaleauto.Value = true;
            app.text_plotrange = uilabel(app.figure1);
            app.text_plotrange.Text = 'Plot range (m)';
            app.edit_plotrange = uieditfield(app.figure1, 'text');
            app.edit_plotrange.ValueChangedFcn = createCallbackFcn(app, @edit_plotrange_Callback, true);
            app.edit_plotrange.Value = '1e-6';
            app.text_hotkeys = uilabel(app.figure1);
            app.text_hotkeys.Text = 'Hotkeys:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassifierTrainer_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ClassifierTrainer_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end