classdef ClassificationTrainer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        axes1                           matlab.ui.control.UIAxes
        uipanel_classification          matlab.ui.container.Panel
        text_classification             matlab.ui.control.Label
        pushbutton_cancelclassification  matlab.ui.control.Button
        pushbutton_addall               matlab.ui.control.Button
        NotrackstoclassifyEditFieldLabel  matlab.ui.control.Label
        NotrackstoclassifyEditField     matlab.ui.control.NumericEditField
        MinimumtrackpointsEditFieldLabel  matlab.ui.control.Label
        MinimumtrackpointsEditField     matlab.ui.control.NumericEditField
        pushbutton_startclassification  matlab.ui.control.Button
        pushbutton_plotTrainingset      matlab.ui.control.Button
        uipanel_categories              matlab.ui.container.Panel
        pushbutton_addcategory          matlab.ui.control.Button
        pushbutton_deletecategory       matlab.ui.control.Button
        listbox_categories              matlab.ui.control.ListBox
        pushbutton_renamecategory       matlab.ui.control.Button
        uipanel_general                 matlab.ui.container.Panel
        pushbutton_newclassifier        matlab.ui.control.Button
        pushbutton_loadclassifier       matlab.ui.control.Button
        pushbutton_saveclassifier       matlab.ui.control.Button
        pushbutton_close                matlab.ui.control.Button
        pushbutton_category1            matlab.ui.control.Button
        pushbutton_category2            matlab.ui.control.Button
        pushbutton_category3            matlab.ui.control.Button
        pushbutton_category4            matlab.ui.control.Button
        text_category1                  matlab.ui.control.Label
        text_category2                  matlab.ui.control.Label
        text_category3                  matlab.ui.control.Label
        text_category4                  matlab.ui.control.Label
        pushbutton_back                 matlab.ui.control.Button
        checkbox_scaleauto              matlab.ui.control.CheckBox
        text_hotkeys                    matlab.ui.control.Label
        PlotrangemEditFieldLabel        matlab.ui.control.Label
        PlotrangemEditField             matlab.ui.control.NumericEditField
        uipanel_segmentationtree        matlab.ui.container.Panel
        pushbutton_computetree          matlab.ui.control.Button
        MaxnumberofsplitsEditFieldLabel  matlab.ui.control.Label
        MaxnumberofsplitsEditField      matlab.ui.control.NumericEditField
        pushbutton_viewtree             matlab.ui.control.Button
        pushbutton_segmentdiffusionlab  matlab.ui.control.Button
        pushbutton_loadsegmentationtree  matlab.ui.control.Button
        pushbutton_savesegmentationtree  matlab.ui.control.Button
        pushbutton_selectfeatures       matlab.ui.control.Button
        pushbutton_predictorimportanceoftree  matlab.ui.control.Button
        uipanel_advanced                matlab.ui.container.Panel
        exportforclassificationlearner  matlab.ui.control.Button
        pushbutton_matlablassificationlearner  matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent % Description
        debugFlag = false; % debug mode
        trainingset % the training set of classified tracks
        trainingpool % pool from which the to-be-classified tracks are drawn
        nClassificationsSeries % number of classificiations in this series
        poolIDX % store the indices of the to be classified tracks in this series
        ctree % classification tree
        selectedFeatures % features selected for classification
    end
    
    methods (Access = private)
        function errorBox(app, err)
            % if there is an error invoked, we output it in a dialog box
            if app.debugFlag
                errordlg(getReport(err,'extended','hyperlinks','off'),'Error');
            else
                errordlg(getReport(err,'basic','hyperlinks','off'),'Error');
            end
        end
        
        function finishedClassification(app)
            
            app.pushbutton_startclassification.UserData = [];
            
            cla(app.axes1)
            
            TracksTS = app.trainingset;
            TrackPool = app.trainingpool;
            sampleIDX = app.poolIDX;
            nManualClassifications = app.nClassificationsSeries;
            sampleIDX = sampleIDX(1:nManualClassifications);
            categorization = app.text_classification.UserData;
            
            TrackPool = TrackPool.resetUnitSystem; % set back to SI
            
            % txt = cell(numel(TracksTS),1);
            for ii = 1:numel(TracksTS)
                take = categorization == ii;
                coords = TrackPool.coords(sampleIDX(take));
                time = TrackPool.time(sampleIDX(take));
                % --- convert to SI
                uFc = TrackPool.getUnitFactor('pixelsize');
                uFt = TrackPool.getUnitFactor('dt');
                coords = cellfun(@(x) x.*uFc,coords,'UniformOutput',false);
                time = cellfun(@(x) x.*uFt,time,'UniformOutput',false);
                % --- add
                TracksTS(ii) = TracksTS(ii).addTrack(time,coords);
            end
            
            app.trainingset = TracksTS;
            
            app.updateText('Finished classification')
            
            app.pushbutton_plotTrainingset_Callback([]) % plot TrackPool
        end
        
        function plotClassification(app)
            
            idx = app.pushbutton_startclassification.UserData;
            nManualClassifications = app.nClassificationsSeries;
            
            cla(app.axes1);
            
            % if we're done with all classifications, stop
            if idx > nManualClassifications
                app.finishedClassification
                return
            end
            
            % else
            
            % set text
            txt = [num2str(idx) '/' num2str(nManualClassifications)];
            app.text_classification.Text = {'Classification in progress:',txt};
            
            % plot
            
            TrackPool = app.trainingpool;
            sampleIDX = app.poolIDX;
            index = sampleIDX(idx);
            [unitFactor,unitLabel] = TrackPool(1).getUnitFactor('pixelsize');
            thisCoords = TrackPool.coords{index}.*unitFactor;
            
            x = thisCoords(:,1);
            y = thisCoords(:,2);
            
            plot(app.axes1, x, y, '-x', ...
                'LineWidth',1.5);
            
            % compute minimum bounding circle and center of mass
            [MBCC, MBCR] = tracks.minboundcircle(x,y,true);
            CoM = mean([x y],1);
            
            plotrange_m = app.PlotrangemEditField.Value; % use userspecified range
            if ~app.checkbox_scaleauto.Value && ~isnan(plotrange_m)
                addLim = plotrange_m/2;
            else % do auto based on MBCR
                addLim = MBCR*1.1;
            end
            xln = [MBCC(1)-addLim MBCC(1)+addLim];
            yln = [MBCC(2)-addLim MBCC(2)+addLim];
            xlim(app.axes1,xln);
            ylim(app.axes1,yln);
            
            hold(app.axes1,'on');
            plot(app.axes1,MBCC(1),MBCC(2),'ro');
            plot(app.axes1,CoM(1),CoM(2),'go');
            hold(app.axes1','off');
            
            xlabel(app.axes1,['x (' unitLabel ')'])
            ylabel(app.axes1,['y (' unitLabel ')'])
            axis(app.axes1, 'equal');
        end
        
        function setClassification(app, nCat)
            
            idx = app.pushbutton_startclassification.UserData;
            app.text_classification.UserData(idx) = nCat;
            
            idx = idx + 1;
            app.pushbutton_startclassification.UserData = idx;

            app.plotClassification
        end
        
        function updateCategories(app)
            
            TracksTS = app.trainingset;
            
            numCat = numel(TracksTS);
            catNames = {TracksTS.name};
            
            app.listbox_categories.Items = catNames; % set names of categories
            app.listbox_categories.ItemsData = 1:numel(catNames); % set index of categories
            
            for ii = 1:4
                thisName = ['pushbutton_category' num2str(ii)];
                thisName2 = ['text_category' num2str(ii)];
                if ii > numCat
                    app.(thisName).Visible = 'off';
                    app.(thisName2).Visible = 'off';
                else
                    app.(thisName).Visible = 'on';
                    app.(thisName2).Visible = 'on';
                    app.(thisName).Text = catNames{ii};
                end
            
            end
        end
        
        function updateText(app, msg)
            
            TracksTS = app.trainingset;
            
            nTracks = [TracksTS.nTracks];
            
            txt = cell(numel(TracksTS),1);
            for ii = 1:numel(TracksTS)
                txt{ii} = [TracksTS(ii).name ': ' num2str(nTracks(ii))];
            end
            
            txt2 = sprintf('Trainings set contains %i tracks.',sum([TracksTS.nTracks]));
            
            app.text_classification.Text = [{msg},...
                {txt2},txt{:}];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ClassifierTrainer_OpeningFcn(app, parent, Tracks, features, debug)
            try
                % store parent
                app.parent = parent;
                
                % store training pool
                if numel(Tracks) ~= 1
                    error('Number of parsed tracks should be one.')
                end
                
                if isempty(Tracks.features)
                    error('No features detected, please compute these first.')
                end
                
                app.trainingpool = Tracks;
                if nargin < 4
                    debug = false;
                end
                
                app.debugFlag = debug;
                
                app.selectedFeatures = app.parent.doFeatures; % set features to be computed
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: checkbox_scaleauto
        function checkbox_scaleauto_Callback(app, event)
            app.plotClassification
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            try
                delete(app)
            catch err
                app.errorBox(err)
            end
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)

            switch event.Character
                case 'z'
                    app.pushbutton_category1_Callback([])
                case 'x'
                    app.pushbutton_category2_Callback([])
                case 'c'
                    app.pushbutton_category3_Callback([])
                case 'v'
                    app.pushbutton_category4_Callback([])
                otherwise
            
            end
        end

        % Button pushed function: pushbutton_addall
        function pushbutton_addall_Callback(app, event)

            app.text_classification.UserData(:) = app.listbox_categories.Value; % set all to selected category
            app.finishedClassification
        end

        % Button pushed function: pushbutton_addcategory
        function pushbutton_addcategory_Callback(app, event)

            try
                TracksTS = app.trainingset;
            
                if ~isempty(TracksTS)
                    numCat = numel(TracksTS);
            
                    if numCat >= 4
                        error('Maximum 4 categories are allowed.')
                    end
            
                    TracksTS = [TracksTS tracks()]; % units pixelsize and dt are initialised to SI by default
                    TracksTS(end).name = ['Category ' num2str(numCat+1)];
            
                    app.trainingset = TracksTS;
            
                    app.updateCategories
                    app.updateText('Added category')
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_back
        function pushbutton_back_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                if isempty(idx)
                    return
                end
            
                if app.pushbutton_startclassification.UserData > 1
                    app.pushbutton_startclassification.UserData = app.pushbutton_startclassification.UserData - 1; % go back
                end
            
                app.plotClassification
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_cancelclassification
        function pushbutton_cancelclassification_Callback(app, event)

            try
            
                classifactionIDX = app.pushbutton_startclassification.UserData;
            
                if ~isempty(classifactionIDX)
            
                    app.pushbutton_startclassification.UserData = []; % only variable checked to mark progress classification
            
                    app.nClassificationsSeries = []; % is not checked, doesnt have to be reset
                    app.text_classification.UserData = []; % is not checked, doesnt have to be reset
            
                    app.updateText('Cancelled classification')
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category1
        function pushbutton_category1_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                if isempty(idx)
                    return
                end
            
                app.setClassification(1)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category2
        function pushbutton_category2_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                numCat = numel(app.trainingset);
                if isempty(idx) || numCat < 2
                    return
                end
            
                app.setClassification(2)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category3
        function pushbutton_category3_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                numCat = numel(app.trainingset);
                if isempty(idx) || numCat < 3
                    return
                end
            
                app.setClassification(3)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_category4
        function pushbutton_category4_Callback(app, event)

            try
                idx = app.pushbutton_startclassification.UserData;
                numCat = numel(app.trainingset);
                if isempty(idx) || numCat < 4
                    return
                end
            
                app.setClassification(4)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_deletecategory
        function pushbutton_deletecategory_Callback(app, event)

            try
                TracksTS = app.trainingset;
            
                if ~isempty(TracksTS)
            
                    numCat = numel(TracksTS);
            
                    if numCat == 1
                        error('Minimum of 1 categories is required.')
                    end
            
                    thisCat = app.listbox_categories.Value;
                    TracksTS(thisCat) = [];
                    app.listbox_categories.Value = 1;
            
                    app.trainingset = TracksTS;
            
                    app.updateCategories
                    app.updateText('Deleted category')
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_loadclassifier
        function pushbutton_loadclassifier_Callback(app, event)
            
            try
                % --- load TrackPool
                [fname,pname,idx] =uigetfile( ...
                    {'*.mat','MATLAB Files (*.mat)'},...
                   'Please select the file containing the TrackPool classifier');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        in = load(fullfile(pname,fname)); % Loads 'TrackPool'
                        fn = fieldnames(in);
                        nTracksObj = 0;
                        for ii = 1:numel(fn)
                            if isa(in.(fn{ii}),'tracks')
                                TracksTS = in.(fn{ii});
                                nTracksObj = nTracksObj + 1;
                            end
                        end
                        if nTracksObj ~= 1
                            error('Expected one ''tracks'' object but found %d instead.',...
                                nTracksObj);
                        end
                        app.trainingset = TracksTS;
                end
            
                app.updateCategories
                app.updateText('Loaded classifier')
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_newclassifier
        function pushbutton_newclassifier_Callback(app, event)
            
            % The classifier is a tracks object array, in which each population is a
            % classified category. In this way, the classifier can be easily plotted
            % and trainings data can be added. However, to allow for extension, the
            % units should be the same, hence, the classifier is always defined in SI
            % units.
            
            try
                TracksTS = tracks({},{},'m','s','name','Category 1','dt',1,'dte',0); % units pixelsize and dt are initialised to SI by default. Thus, first convert added tracks to SI units.
            
                app.trainingset = TracksTS;
            
                app.updateCategories
                app.updateText('Started new classifier')
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_close
        function pushbutton_close_Callback(app, event)
            
            try
                TrackPool = app.trainingpool; % give original data back
                app.pushbutton_close.UserData = TrackPool;
                app.pushbutton_close.UserData = [];
                app.figure1_CloseRequestFcn([])
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_plotTrainingset
        function pushbutton_plotTrainingset_Callback(app, event)

            try
            
                TracksTS = app.trainingset;
            
                if ~isempty(TracksTS)
            
                    if any([TracksTS.nTracks] == 0)
            
                        nTracks = [TracksTS.nTracks];
                        idx = nTracks == 0; % cannot plot empty tracks
            
                        TracksTS(idx) = [];
            
                    end
            
                    figure
                    hps = TracksTS.plotTracks;
            
                    % manually set legend
                    nCat = numel(TracksTS);
                    legendTxt = cell(nCat,1);
                    legendN = cumsum([TracksTS.nTracks]);
                    legendHps = zeros(nCat,1);
                    for ii = 1:nCat
                        legendTxt{ii} = TracksTS(ii).name;
                        legendHps(ii) = hps(legendN(ii));
                    end
                    legend(legendHps,legendTxt{:})
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_renamecategory
        function pushbutton_renamecategory_Callback(app, event)

            try
            
                TracksTS = app.trainingset;
            
                if ~isempty(TracksTS)
            
                    thisCat = app.listbox_categories.Value;
            
                    prompt = {'Enter category name:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {TracksTS(thisCat).name};
                    newName = inputdlg(prompt,dlgtitle,dims,definput);
                    TracksTS(thisCat).name = newName{1};
            
                    app.trainingset = TracksTS;
            
                    app.updateCategories
                    app.updateText('Renamed category')
            
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_saveclassifier
        function pushbutton_saveclassifier_Callback(app, event)
            try
                TracksTS = app.trainingset;
                if ~isempty(TracksTS)
                    [fname,pname,idx] = uiputfile('.mat','Save tracks classifier');
                    if idx == 0
                        return % user pressed cancel
                    end
                    wb = waitbar(0.5,'Saving classifier...','Name','Please wait');
                    save(fullfile(pname,fname),'TracksTS','-v7.3');
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_startclassification
        function pushbutton_startclassification_Callback(app, event)

            try
                TracksTS = app.trainingset;
                if ~isempty(TracksTS)
                    TrackPool = app.trainingpool;
                    nManualClassifications = app.NotrackstoclassifyEditField.Value;
                    if ~isnan(nManualClassifications)
                        doneIDX = app.poolIDX;
            
                        tooShortIDX = TrackPool.nTrackPoints < app.MinimumtrackpointsEditField.Value;
                        tooShortIDX(doneIDX) = true;
                        goodIDX = find(~tooShortIDX);
            
                        if numel(goodIDX) >= nManualClassifications
                            sampleIDX = randsample(goodIDX,nManualClassifications);
                            app.poolIDX = vertcat(sampleIDX,doneIDX); % store the done idx without classifying them, because numel(sampleIDX) == nClassifications
                            app.pushbutton_startclassification.UserData = 1; % start at one
                            app.nClassificationsSeries = nManualClassifications;
                            app.text_classification.UserData = nan(nManualClassifications,1);
                            app.plotClassification;
                        else
                            error('The number of remaining tracks (%i) is smaller than the training set (%i).',numel(goodIDX),nManualClassifications);
                        end
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: exportforclassificationlearner
        function exportforclassificationlearnerButtonPushed(app, event)
            try
                if ~isempty(app.trainingset) && sum([app.trainingset.nTracks]) > 0
                    % file dialog
                    [fname,pname,idx] = uiputfile( ...
                        {'*.txt', 'Text file (*.txt)';...
                         '*.csv','Comma-separated values (*.csv)';...
                         '*.xlsx','Microsoft Excel (*.xlsx)';...
                         '*.mat','MATLAB File (*.mat)';
                         '*.*',  'All Files (*.*)'}, 'Save properties');
                     
                    if idx == 0
                        return % user pressed cancel
                    end
                    
                    % we don't want to store the properties, because this
                    % takes a lot of memory, instead, we simply compute it
                    % only when exporting the table
                    Tracks = app.trainingset;
                    
%                     % set diffusion constant estimator
%                     disp('Setting default normal MSD diffusion analysis')
%                     Tracks = Tracks.setArrayProperty('Dest',Dest.MSD);
                    
                    
                    % --- compute properties
                    Tracks = Tracks.computeFeatures(false,app.selectedFeatures,app.parent.filepathFeatures);
                    wb = waitbar(0.5,'Saving','Name','Computing all TrackPool properties');

                    % saving
                    if idx == 5 || idx == 6
                        table = Tracks.exportPropertyTable();
                        save(fullfile(pname,fname),'table','-v7.3');
                    else
                        table = Tracks.exportPropertyTable();
                        writetable(table,fullfile(pname,fname))
                    end
                    
                    disp('Saved property table to:')
                    disp(fullfile(pname,fname))
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: 
        % pushbutton_matlablassificationlearner
        function pushbutton_matlablassificationlearnerButtonPushed(app, event)
            try    
                classificationLearner
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_computetree
        function pushbutton_computetreeButtonPushed(app, event)
            try
                app.trainingset =  app.trainingset.computeFeatures(false,app.selectedFeatures,app.parent.filepathFeatures); % always use custom features
                T = app.trainingset.exportPropertyTable();
                app.ctree = fitctree(T,'Classifier',"MaxNumSplits",app.MaxnumberofsplitsEditField.Value); % create classification tree with 'Classifier' as classification variable
                view(app.ctree) % text description
                view(app.ctree,'mode','graph');
                resuberror = resubLoss(app.ctree);
                cvctree = crossval(app.ctree);
                cvloss = kfoldLoss(cvctree);
                fprintf('The resubstitution loss was %f and classification loss for observations not used for training was %f.',resuberror,cvloss)
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_viewtree
        function pushbutton_viewtreeButtonPushed(app, event)
            try
                if ~isempty(app.ctree)
                    view(app.ctree) % text description
                    view(app.ctree,'mode','graph');
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_loadsegmentationtree
        function pushbutton_loadsegmentationtreeButtonPushed(app, event)
            try
                % --- load
                [fname,pname,idx] =uigetfile( ...
                    {'*.mat','MATLAB Files (*.mat)'},...
                   'Please select the file containing the classification tree');
            
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        in = load(fullfile(pname,fname)); % Loads 'ctree'
                        fn = fieldnames(in);
                        nObj = 0;
                        for ii = 1:numel(fn)
                            if isa(in.(fn{ii}),'ClassificationTree')
                                loadctree = in.(fn{ii});
                                nObj = nObj + 1;
                            end
                        end
                        if nObj ~= 1
                            error('Expected one ''ClassificationTree'' object but found %d instead.',...
                                nObj);
                        end
                        app.ctree = loadctree;
                end
                disp('Loaded classification tree')
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_segmentdiffusionlab
        function pushbutton_segmentdiffusionlabButtonPushed(app, event)
            try
                if ~isempty(app.ctree)
                    % --- compute properties
                    ii = app.parent.DropDown_PopulationNbr.Value;
                    nTracks = numel(app.parent.Tracks);
                    Tracks = app.parent.Tracks(ii); % get current population
                    Tracks = Tracks.computeFeatures(false,app.parent.doFeatures,app.parent.filepathFeatures); % compute all features to make sure that nTrackPoints is availablt for the table in DiffusionLab
                    T = Tracks.exportPropertyTable();
                    C = predict(app.ctree,T);
                    uC = unique(C); % get categories
                    if numel(uC) > 1
                        segTree = Tracks.segTree;
%                         TrackSegmented = tracks.empty(numel(uC),0); %
%                         preallocation fails assignement populations to
%                         variable.. why??!
                        for jj = 1:numel(uC)
                            TrackSegmented(jj,1) = Tracks.segment(ismember(C,uC{jj}));
                        end
                        segTree = segTree.add(ii,uC{:});
                        app.parent.Tracks(ii) = TrackSegmented(1);
                        app.parent.Tracks = vertcat(app.parent.Tracks(:),TrackSegmented(2:end));
                        app.parent.Tracks = app.parent.Tracks.setArrayProperty('segTree',segTree);
                        delete(app)
                    else
                        warndlg('All tracks are segmented in to the same population','Warning');
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_savesegmentationtree
        function pushbutton_savesegmentationtreeButtonPushed(app, event)
            try
                ctree_ = app.ctree;
                if ~isempty(ctree_)
                    [fname,pname,idx] = uiputfile('.mat','Save classification tree');
                    if idx == 0
                        return % user pressed cancel
                    end
                    wb = waitbar(0.5,'Saving classication tree...','Name','Please wait');
                    save(fullfile(pname,fname),'ctree_','-v7.3');
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: pushbutton_selectfeatures
        function pushbutton_selectfeaturesButtonPushed(app, event)
            try
                if isempty(app.trainingset)
                    msgbox('Please create a classifier first')
                else
                    listFeatures = app.parent.doFeatures; % get available features
                    selectedFeat = find(ismember(listFeatures,app.selectedFeatures)); % get idx selected features
                    idx = listdlg('ListString',listFeatures,'SelectionMode','multiple','PromptString',{'Please select the features used','for segmentation:','Features in feature script are','automatically included.'},'InitialValue',selectedFeat);
                    app.selectedFeatures = listFeatures(idx);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: 
        % pushbutton_predictorimportanceoftree
        function pushbutton_predictorimportanceoftreeButtonPushed(app, event)
            try
                ctree_ = app.ctree;
                if ~isempty(ctree_)
                    imp = predictorImportance(ctree_);
                    figure;
                    bar(imp);
                    title('Predictor Importance Estimates');
                    ylabel('Estimates');
                    xlabel('Predictors');
                    h = gca;
                    h.XTickLabel = ctree_.PredictorNames;
                    h.XTickLabelRotation = 45;
                    h.TickLabelInterpreter = 'none';
                end
            catch err
                app.errorBox(err)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Classification Trainer (for DiffusionLab)';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.axes1 = uiaxes(app.figure1);
            app.uipanel_classification = uipanel(app.figure1);
            app.uipanel_classification.Title = 'Traning';
            app.text_classification = uilabel(app.uipanel_classification);
            app.text_classification.Text = 'No training started';
            app.pushbutton_cancelclassification = uibutton(app.uipanel_classification, 'push');
            app.pushbutton_cancelclassification.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancelclassification_Callback, true);
            app.pushbutton_cancelclassification.Text = 'Cancel traning';
            app.pushbutton_addall = uibutton(app.uipanel_classification, 'push');
            app.pushbutton_addall.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_addall_Callback, true);
            app.pushbutton_addall.Text = 'Add all tracks to current category';
            app.NotrackstoclassifyEditFieldLabel = uilabel(app.uipanel_classification);
            app.NotrackstoclassifyEditFieldLabel.Text = 'No. tracks to classify';
            app.NotrackstoclassifyEditField = uieditfield(app.uipanel_classification, 'numeric');
            app.NotrackstoclassifyEditField.LowerLimitInclusive = 'off';
            app.NotrackstoclassifyEditField.UpperLimitInclusive = 'off';
            app.NotrackstoclassifyEditField.Limits = [0 Inf];
            app.NotrackstoclassifyEditField.RoundFractionalValues = 'on';
            app.NotrackstoclassifyEditField.Value = 100;
            app.MinimumtrackpointsEditFieldLabel = uilabel(app.uipanel_classification);
            app.MinimumtrackpointsEditFieldLabel.Text = 'Minimum track points';
            app.MinimumtrackpointsEditField = uieditfield(app.uipanel_classification, 'numeric');
            app.MinimumtrackpointsEditField.LowerLimitInclusive = 'off';
            app.MinimumtrackpointsEditField.UpperLimitInclusive = 'off';
            app.MinimumtrackpointsEditField.Limits = [0 Inf];
            app.MinimumtrackpointsEditField.RoundFractionalValues = 'on';
            app.MinimumtrackpointsEditField.Value = 5;
            app.pushbutton_startclassification = uibutton(app.uipanel_classification, 'push');
            app.pushbutton_startclassification.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_startclassification_Callback, true);
            app.pushbutton_startclassification.Text = 'Start training';
            app.pushbutton_plotTrainingset = uibutton(app.uipanel_classification, 'push');
            app.pushbutton_plotTrainingset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotTrainingset_Callback, true);
            app.pushbutton_plotTrainingset.Text = 'Plot trainings set';
            app.uipanel_categories = uipanel(app.figure1);
            app.uipanel_categories.Title = 'Categories';
            app.pushbutton_addcategory = uibutton(app.uipanel_categories, 'push');
            app.pushbutton_addcategory.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_addcategory_Callback, true);
            app.pushbutton_addcategory.Text = 'Add category';
            app.pushbutton_deletecategory = uibutton(app.uipanel_categories, 'push');
            app.pushbutton_deletecategory.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_deletecategory_Callback, true);
            app.pushbutton_deletecategory.Text = 'Delete category';
            app.listbox_categories = uilistbox(app.uipanel_categories);
            app.listbox_categories.Items = {'No classifier loaded'};
            app.listbox_categories.Value = 'No classifier loaded';
            app.pushbutton_renamecategory = uibutton(app.uipanel_categories, 'push');
            app.pushbutton_renamecategory.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_renamecategory_Callback, true);
            app.pushbutton_renamecategory.Text = 'Rename category';
            app.uipanel_general = uipanel(app.figure1);
            app.uipanel_general.Title = 'General';
            app.pushbutton_newclassifier = uibutton(app.uipanel_general, 'push');
            app.pushbutton_newclassifier.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_newclassifier_Callback, true);
            app.pushbutton_newclassifier.Text = 'New training set';
            app.pushbutton_loadclassifier = uibutton(app.uipanel_general, 'push');
            app.pushbutton_loadclassifier.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadclassifier_Callback, true);
            app.pushbutton_loadclassifier.Text = 'Load training set';
            app.pushbutton_saveclassifier = uibutton(app.uipanel_general, 'push');
            app.pushbutton_saveclassifier.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveclassifier_Callback, true);
            app.pushbutton_saveclassifier.Text = 'Save training set as';
            app.pushbutton_close = uibutton(app.uipanel_general, 'push');
            app.pushbutton_close.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_close_Callback, true);
            app.pushbutton_close.Text = 'Close';
            app.pushbutton_category1 = uibutton(app.figure1, 'push');
            app.pushbutton_category1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category1_Callback, true);
            app.pushbutton_category1.Visible = 'off';
            app.pushbutton_category1.Text = 'Category 1';
            app.pushbutton_category2 = uibutton(app.figure1, 'push');
            app.pushbutton_category2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category2_Callback, true);
            app.pushbutton_category2.Visible = 'off';
            app.pushbutton_category2.Text = 'Category 2';
            app.pushbutton_category3 = uibutton(app.figure1, 'push');
            app.pushbutton_category3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category3_Callback, true);
            app.pushbutton_category3.Visible = 'off';
            app.pushbutton_category3.Text = 'Category 3';
            app.pushbutton_category4 = uibutton(app.figure1, 'push');
            app.pushbutton_category4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_category4_Callback, true);
            app.pushbutton_category4.Visible = 'off';
            app.pushbutton_category4.Text = 'Category 4';
            app.text_category1 = uilabel(app.figure1);
            app.text_category1.Visible = 'off';
            app.text_category1.Text = 'z';
            app.text_category2 = uilabel(app.figure1);
            app.text_category2.Visible = 'off';
            app.text_category2.Text = 'x';
            app.text_category3 = uilabel(app.figure1);
            app.text_category3.Visible = 'off';
            app.text_category3.Text = 'c';
            app.text_category4 = uilabel(app.figure1);
            app.text_category4.Visible = 'off';
            app.text_category4.Text = 'v';
            app.pushbutton_back = uibutton(app.figure1, 'push');
            app.pushbutton_back.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_back_Callback, true);
            app.pushbutton_back.Text = 'Go back';
            app.checkbox_scaleauto = uicheckbox(app.figure1);
            app.checkbox_scaleauto.ValueChangedFcn = createCallbackFcn(app, @checkbox_scaleauto_Callback, true);
            app.checkbox_scaleauto.Text = 'auto';
            app.checkbox_scaleauto.Value = true;
            app.text_hotkeys = uilabel(app.figure1);
            app.text_hotkeys.Text = 'Hotkeys:';
            app.PlotrangemEditFieldLabel = uilabel(app.figure1);
            app.PlotrangemEditFieldLabel.Text = 'Plot range (m)';
            app.PlotrangemEditField = uieditfield(app.figure1, 'numeric');
            app.PlotrangemEditField.LowerLimitInclusive = 'off';
            app.PlotrangemEditField.UpperLimitInclusive = 'off';
            app.PlotrangemEditField.Limits = [0 Inf];
            app.PlotrangemEditField.Value = 1e-06;
            app.uipanel_segmentationtree = uipanel(app.figure1);
            app.uipanel_segmentationtree.Title = 'Segmentation tree';
            app.pushbutton_computetree = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_computetree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_computetreeButtonPushed, true);
            app.pushbutton_computetree.Text = 'Compute tree';
            app.MaxnumberofsplitsEditFieldLabel = uilabel(app.uipanel_segmentationtree);
            app.MaxnumberofsplitsEditFieldLabel.Text = 'Max. number of splits';
            app.MaxnumberofsplitsEditField = uieditfield(app.uipanel_segmentationtree, 'numeric');
            app.MaxnumberofsplitsEditField.UpperLimitInclusive = 'off';
            app.MaxnumberofsplitsEditField.RoundFractionalValues = 'on';
            app.MaxnumberofsplitsEditField.Value = 10;
            app.pushbutton_viewtree = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_viewtree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_viewtreeButtonPushed, true);
            app.pushbutton_viewtree.Text = 'View tree';
            app.pushbutton_segmentdiffusionlab = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_segmentdiffusionlab.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_segmentdiffusionlabButtonPushed, true);
            app.pushbutton_segmentdiffusionlab.Text = 'Segment DiffusionLab';
            app.pushbutton_loadsegmentationtree = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_loadsegmentationtree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadsegmentationtreeButtonPushed, true);
            app.pushbutton_loadsegmentationtree.Text = 'Load segmentation tree';
            app.pushbutton_savesegmentationtree = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_savesegmentationtree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_savesegmentationtreeButtonPushed, true);
            app.pushbutton_savesegmentationtree.Text = 'Save segmentation tree as';
            app.pushbutton_selectfeatures = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_selectfeatures.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectfeaturesButtonPushed, true);
            app.pushbutton_selectfeatures.Text = 'Select features';
            app.pushbutton_predictorimportanceoftree = uibutton(app.uipanel_segmentationtree, 'push');
            app.pushbutton_predictorimportanceoftree.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_predictorimportanceoftreeButtonPushed, true);
            app.pushbutton_predictorimportanceoftree.Text = 'Predictor importance of tree';
            app.uipanel_advanced = uipanel(app.figure1);
            app.uipanel_advanced.Title = 'Advanced';
            app.exportforclassificationlearner = uibutton(app.uipanel_advanced, 'push');
            app.exportforclassificationlearner.ButtonPushedFcn = createCallbackFcn(app, @exportforclassificationlearnerButtonPushed, true);
            app.exportforclassificationlearner.Text = 'Export for Classification Learner';
            app.pushbutton_matlablassificationlearner = uibutton(app.uipanel_advanced, 'push');
            app.pushbutton_matlablassificationlearner.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_matlablassificationlearnerButtonPushed, true);
            app.pushbutton_matlablassificationlearner.Text = 'MATLAB Classification Learner';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassificationTrainer_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ClassifierTrainer_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end