classdef DiffusionLab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        LoadfeaturescriptMenu        matlab.ui.container.Menu
        ImporttracksMenu             matlab.ui.container.Menu
        DoMMenu                      matlab.ui.container.Menu
        LocalizerMenu                matlab.ui.container.Menu
        COMSOL2DMenu                 matlab.ui.container.Menu
        COMSOL3DMenu                 matlab.ui.container.Menu
        ExportMenu_2                 matlab.ui.container.Menu
        FigureMenu                   matlab.ui.container.Menu
        TrackstoworkspaceMenu        matlab.ui.container.Menu
        PropertiestotableMenu        matlab.ui.container.Menu
        TrackMSDstofileMenu          matlab.ui.container.Menu
        PopulationMSDstofileMenu     matlab.ui.container.Menu
        CloseallopenfiguresMenu      matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        ClassificationMenu           matlab.ui.container.Menu
        ClassificationTrainerMenu    matlab.ui.container.Menu
        SegmentwithmodelCLMenu       matlab.ui.container.Menu
        MATLABClassificationLearnerMenu  matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        UnitsMenu                    matlab.ui.container.Menu
        LengthMenu                   matlab.ui.container.Menu
        TimeMenu                     matlab.ui.container.Menu
        ResettoSIunitsMenu           matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        DocumentationMenu            matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        DebugMenu                    matlab.ui.container.Menu
        panel_plotproperties         matlab.ui.container.Panel
        LinethicknessEditFieldLabel  matlab.ui.control.Label
        LinethicknessEditField       matlab.ui.control.NumericEditField
        button_plotselectedmsd       matlab.ui.control.Button
        button_plotselectedtrack     matlab.ui.control.Button
        DropDown_colormap            matlab.ui.control.DropDown
        button_PlotTracks            matlab.ui.control.Button
        checkbox_plotTracksLabel     matlab.ui.control.CheckBox
        button_plotmsd               matlab.ui.control.Button
        button_plotSD                matlab.ui.control.Button
        button_plotMSDpop            matlab.ui.control.Button
        checkbox_plotallpopulations  matlab.ui.control.CheckBox
        DelayindexEditFieldLabel     matlab.ui.control.Label
        DelayindexEditField          matlab.ui.control.EditField
        PlotsquareddisplacementCPDButton  matlab.ui.control.Button
        checkbox_NoCoM               matlab.ui.control.CheckBox
        checkbox_xlog                matlab.ui.control.CheckBox
        button_PlotTrackPropCorr     matlab.ui.control.Button
        button_voronoidiag           matlab.ui.control.Button
        checkbox_NoEdges             matlab.ui.control.CheckBox
        button_histogram1stprop      matlab.ui.control.Button
        checkbox_probabilityhist     matlab.ui.control.CheckBox
        button_boxplot1stprop        matlab.ui.control.Button
        BiplotPCAButton              matlab.ui.control.Button
        panel_Dest                   matlab.ui.container.Panel
        panel_selection              matlab.ui.container.Panel
        panel_computeproperties      matlab.ui.container.Panel
        button_computeprops          matlab.ui.control.Button
        PixelsizenmEditFieldLabel    matlab.ui.control.Label
        PixelsizenmEditField         matlab.ui.control.NumericEditField
        FrametimesEditFieldLabel     matlab.ui.control.Label
        FrametimesEditField          matlab.ui.control.NumericEditField
        ExposuretimesEditFieldLabel  matlab.ui.control.Label
        ExposuretimesEditField       matlab.ui.control.NumericEditField
        panel_segmentation           matlab.ui.container.Panel
        button_useAsFilter           matlab.ui.control.Button
        button_resetFilter           matlab.ui.control.Button
        button_plotRemainingTracks   matlab.ui.control.Button
        button_plotRemainingMSDs     matlab.ui.control.Button
        button_applyfiltertoall      matlab.ui.control.Button
        button_delCurrentPop         matlab.ui.control.Button
        text_propThList              matlab.ui.control.Label
        button_plotDtree             matlab.ui.control.Button
        PropertythresholdEditFieldLabel  matlab.ui.control.Label
        PropertythresholdEditField   matlab.ui.control.NumericEditField
        button_delCurrentPop_2       matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        button_copytable             matlab.ui.control.Button
        DropDown_TrackNbr            matlab.ui.control.DropDown
        DropDown_PopulationNbr       matlab.ui.control.DropDown
        table1                       matlab.ui.control.Table
        DropDown_loadDest            matlab.ui.control.DropDown
        DropDown_TrackProp1          matlab.ui.control.DropDown
        DropDown_TrackProp2          matlab.ui.control.DropDown
        button_DestOptions           matlab.ui.control.Button
        button_setcurrentDesttoall   matlab.ui.control.Button
        button_computediffusion      matlab.ui.control.Button
        checkbox_computeDallpop      matlab.ui.control.CheckBox
        DropDown_Dmodel              matlab.ui.control.DropDown
        button_reportDest            matlab.ui.control.Button
        button_DestPlots             matlab.ui.control.Button
        button_plotaptialmaphexgrid  matlab.ui.control.Button
        LogarithmicCheckBox          matlab.ui.control.CheckBox
        BinsizenmEditFieldLabel      matlab.ui.control.Label
        BinsizenmEditField           matlab.ui.control.NumericEditField
    end

    properties (Constant, Access = private)
        version = '1.0.1'
    end
    
    properties (Access = public) % public properties for CLassification Trainer
        Tracks % tracks object
%         TrackProperties % cell array with possible track properties: name, location in obj.(location), unit for unitFactor, column of data
        printFig = printFig; % printFig object
        doFeatures = {... % features to be computed
            'nTrackPoints',...
            'MinBoundCircleRadius',... % radius of the minimum enclosing circle
            'MBCCminusCoM',... % minimum enclosing circle minus center of mass distance
            'entropy',... %  Shannon's entropy of the track path
            'trackLength',... % length of track
            'tortuosity',... % length of track divided by the Euclidean end to end distance
            'wEV1',... % normalized weight first principal component
            'EV1angle',... % angle between first and second principal component [degree]
            }; % 
        filepathFeatures = []; % filepaths to custom features provided by the user.
    end
    
    methods (Access = private)
        function errorBox(app, err)
            % if there is an error invoked, we output it in a dialog box
            if strcmp(app.DebugMenu.Checked,'on')
                errordlg(getReport(err,'extended','hyperlinks','off'),'Error');
            else
                errordlg(getReport(err,'basic','hyperlinks','off'),'Error');
            end
        end
        
        function initGUI(app)
            
%             % --- store wanted properties
%             % name, location in obj.(location), unit for unitFactor,
%             % column of data
%             app.TrackProperties = {...
%                 'Number of points','nTrackPoints',[],1;...
%                 'MBC radius','MinBoundCircleRadius','pixelsize',1;...
%             	'MBCC minus CoM','MBCCminusCoM',[],1;...
%             	'Entropy','entropy',[],1;...
%                 'Elongation','wEV1',[],1;...
%             	'Elongation angle','EV1angle',[],1;...
%                 'Diffusion constant','D', 'pixelsize.^2./dt',1;...
% %                 'Diffusion constant 2','Dest.results.D', 'pixelsize.^2./dt',2;...
% %                 'Diffusion constant 3','Dest.results.D', 'pixelsize.^2./dt',3;...
% %                 'Diffusion constant 4','Dest.results.D', 'pixelsize.^2./dt',4;...
% %                 'Diffusion constant 5','Dest.results.D', 'pixelsize.^2./dt',5;...
%                 'Localization variance','locVar', 'pixelsize.^2',1;...
%             	'Diffusion SNR','diffusionSNR',[],1;...
%             	'Mean intensity','meanintensity',[],1;...
%             	'Max intensity','maxintensity',[],1;...
%             	'Length','trackLength','pixelsize',1;...
%             	'Tortuosity','tortuosity',[],1};
%             
%             
            
            
            % --- set colormaps
            
            p = mfilename('fullpath');
            [p,~] = fileparts(p);
            cmapStruct = dir(fullfile(p,['..' filesep 'colormaps' filesep '*.m']));
            cmapNames = cell(1,numel(cmapStruct));
            for ii = 1:numel(cmapStruct)
                [~,cmapNames{ii},~] = fileparts(cmapStruct(ii).name);
            end
            app.DropDown_colormap.Items = [app.DropDown_colormap.Items, cmapNames];
            
        end
        
        function initTracks(app)
            
            % --- load GUI settings to object
            
            % just invoke the callback for all edit fields
            app.PixelsizenmEditFieldValueChanged([]);
            app.FrametimesEditFieldValueChanged([]);
            app.ExposuretimesEditFieldValueChanged([]);
            app.LinethicknessEditFieldValueChanged([]);
            app.DropDown_colormap_Callback([]);
            DestNames = {'MSD'};%,'CPD'};
            DestNames = [{'No diffusion estimator'} DestNames]; % append none; CHECKKKKKK
            app.DropDown_loadDest.Items = DestNames;
            app.DropDown_loadDest.ItemsData = 1:numel(DestNames);
            
            % set diffusion constant estimator menu
            ii = 1;
            if ~isempty(app.Tracks(ii).Dest)
                b = strcmp(app.Tracks(ii).Dest.estName,app.DropDown_loadDest.Items);
                if any(b)
                    app.DropDown_loadDest.Value = find(b); % get index
                    app.DropDown_loadDest_Callback([]) % set Dmodel via callback load estimator
                end
            end

            app.Tracks(1).segTree = app.Tracks(1).segTree.init(numel(app.Tracks)); % reset segmentation tree
            app.Tracks = app.Tracks.setArrayProperty('segTree',app.Tracks(1).segTree);

        end
        
        function setTrackLabels(app)
            units = 'pixelsize';
            if app.checkbox_plotallpopulations.Value
                kk = 1;
                th = gobjects(sum([app.Tracks.nTracks]),1);
                for ii = 1:numel(app.Tracks)
                    uF = app.Tracks(ii).getUnitFactor(units);
                    for jj = 1:app.Tracks(ii).nTracks
                        th(kk) = text(gca,app.Tracks(ii).coords{jj}(1,1).*uF,app.Tracks(ii).coords{jj}(1,2).*uF,num2str(jj));
                        kk = kk + 1;
                    end
                end
            else
                ii = app.DropDown_PopulationNbr.Value;
                pixelsize = app.Tracks(ii).getUnit('pixelsize');
                uF = app.Tracks(ii).getUnitFactor(pixelsize);
                th = gobjects(app.Tracks(ii).nTracks,1);
                for jj = 1:app.Tracks(ii).nTracks
                    th(jj) = text(gca,app.Tracks(ii).coords{jj}(1,1).*uF,app.Tracks(ii).coords{jj}(1,2).*uF,num2str(jj));
                end
            end
            app.checkbox_plotTracksLabel.UserData = th;
        end
        
        function updateTable(app)
            ii = app.DropDown_PopulationNbr.Value;
            if ~isempty(app.Tracks)
                app.Tracks(ii) = app.Tracks(ii).sortFeatureTable; % sort table
%                 txt1 = app.DropDown_TrackProp1.ItemsData;
                idx1 = app.DropDown_TrackProp1.Value;
                [TrackProp,unit,dispPropName] = app.Tracks(ii).getFeature(idx1,true);
                if ~isempty(unit)
                    [uF,uL] = app.Tracks(ii).getUnitFactor(unit);
                    TrackProp = uF.*TrackProp; % convert units
                    dispPropName = [dispPropName ' (' uL ')']; % add units to display name
                end
                [TrackProp_sorted,I]=sort(TrackProp,1);
                IDX_sorted = I; % use index of sorting, so that the trackID can still be unique over all populations
                TD = [IDX_sorted(:,1) TrackProp_sorted];
                set(app.table1,'Data',TD);
                set(app.table1,'ColumnName',{'Track ID',dispPropName});
                % set popup to right Dest
                if isempty(app.Tracks(ii).Dest)
                    app.DropDown_loadDest.Value = 1;
                else
                    classname = class(app.Tracks(ii).Dest);
                    nPopup = strcmp(app.DropDown_loadDest.Items,classname(6:end)); % remove Dest. from name
                    app.DropDown_loadDest.Value = find(nPopup == 1);
                end
            end
        end
        
        function updateTrackPopulationPopups(app)
            
            popupTxt = cell(numel(app.Tracks),1);
            for ii=1:numel(app.Tracks)
                popupTxt{ii} = sprintf('Population %i',ii);
            end
            app.DropDown_PopulationNbr.Items = popupTxt;
            app.DropDown_PopulationNbr.ItemsData = 1:numel(app.Tracks);
            app.DropDown_PopulationNbr.Value = 1;
            app.DropDown.Items = string(1:numel(app.Tracks)); % dropdown for merge option
            app.DropDown.ItemsData = 1:numel(app.Tracks);
            app.DropDown.Value = 1;
            app.updateTrackPropPopups
            app.updateTable
        end
        
        function updateTrackPropPopups(app)
            
            pp = app.DropDown_PopulationNbr.Value;
            popupTxt = cell(app.Tracks(pp).nTracks,1);
            for ii=1:app.Tracks(pp).nTracks
                popupTxt{ii} = sprintf('Track %i',ii);
            end
            app.DropDown_TrackNbr.Items = popupTxt;
            app.DropDown_TrackNbr.ItemsData = 1:app.Tracks(pp).nTracks;
            
            % check features in feature table. Items is display name, and
            % ItemsData the variable name
            app.DropDown_TrackProp1.Items = app.Tracks(pp).features.Properties.VariableDescriptions';
            app.DropDown_TrackProp1.ItemsData = app.Tracks(pp).features.Properties.VariableNames';
            clear = strcmp(app.DropDown_TrackProp1.Items,'Track ID');
            app.DropDown_TrackProp1.Items(clear) = [];
            app.DropDown_TrackProp1.ItemsData(clear) = [];
            app.DropDown_TrackProp1.Value = 'nTrackPoints';
            app.DropDown_TrackProp2.Items = app.Tracks(pp).features.Properties.VariableDescriptions';
            app.DropDown_TrackProp2.ItemsData = app.Tracks(pp).features.Properties.VariableNames';
            clear = strcmp(app.DropDown_TrackProp2.Items,'Track ID');
            app.DropDown_TrackProp2.Items(clear) = [];
            app.DropDown_TrackProp2.ItemsData(clear) = [];
            app.DropDown_TrackProp2.Value = 'nTrackPoints';
            
        end
        
    end
    
    % static methods
    
    methods (Static, Access = private)
        
        function f_clipboard(d)
            size_d = size(d);
            str = '';
            for ii = 1:size_d(1)
                for jj = 1:size_d(2)
                    if jj == size_d(2)
                        str = sprintf('%s%e',str,d(ii,jj));
                    else
                        str = sprintf('%s%e\t',str,d(ii,jj));
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DiffusionLab_OpeningFcn(app, tracks)

            
            % always intialize
            try
                % add paths
%                 p = mfilename('fullpath');
%                 [p,~] = fileparts(p);
                
%                 addpath(genpath(fullfile(p,['..' filesep 'GUI'])))
%                 addpath(genpath(fullfile(p,['..' filesep 'classes'])))
%                 addpath(genpath(fullfile(p,['..' filesep 'colormaps'])))
%                 addpath(genpath(fullfile(p,['..' filesep 'third_party'])))
                
                app.initGUI;
                disp(['DiffusionLab v. ' app.version])
                disp(char(datetime('now')))
            catch err
                app.errorBox(err)
            end
            
%             app.DebugMenu.Checked = 'on';
            
            % now set the itemsdata, because we cannot set the Itemsdata to
            % be numeric by startup. The numeric items data is reuiqred for
            % a correct initaization of the diffusion estimator panel upon
            % loading of a tracks object with a predefined diffusion
            % estimator.
            app.DropDown_PopulationNbr.ItemsData = 1;
            
            % due to try statement,
            try
                if nargin == 2
                    if isa(tracks,'tracks')
                        % --- set GUI
                        app.Tracks = tracks;
                        app.initTracks;
                        app.updateTrackPopulationPopups; % also calls updateTrackPropPopups
                        app.updateTable;
                        disp('Done');
                    else
                        error('Input is not ''tracks'' object.')
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: checkbox_plotTracksLabel
        function checkbox_plotTracksLabel_Callback(app, event)
            
            try
                if ~isempty(app.checkbox_plotTracksLabel.UserData)
                    th = app.checkbox_plotTracksLabel.UserData;
                    if any(~isvalid(th))
                        app.checkbox_plotTracksLabel.UserData = [];
                    else
                        if app.checkbox_plotTracksLabel.Value
                            for ii = 1:numel(th)
                                th(ii).Visible = 'on';
                            end
                        else
                            for ii = 1:numel(th)
                                th(ii).Visible = 'off';
                            end
                        end
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function menu_classificationtrainer_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
            %         app.WindowStyle = 'modal';
                    TrackClass = ClassifierTrainer([],app.Tracks(ii),ii,handles);
                    if isempty(TrackClass)
                        return
                    end
            %         app.WindowStyle = 'normal';
                    % if not empty
                    app.Tracks(ii) = TrackClass(1);
                    if numel(TrackClass) > 1
                        app.Tracks = vertcat(app.Tracks(:), TrackClass(2:end)); % in ClassifierTrainer Tracks are column array
                    end
                    app.Tracks = app.Tracks.setArrayProperty('segTree',app.Tracks(ii).segTree); % save updated segmentation tree
                    app.updateTrackPopulationPopups;
                    app.updateTable;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_Dmodel
        function DropDown_Dmodel_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    if any(strcmp(app.DropDown_Dmodel.Value,{'Select diffusion model','No model available'}))
                        % no estimator exists or does not allow models to be selected...
                    elseif strcmp(app.Tracks(ii).Dest.DModel.name,app.DropDown_Dmodel.Value)
                        % if is loaded already
                    else
                        app.Tracks(ii).Dest.DModel = Dest.(app.DropDown_Dmodel.Value);
                    end
                    app.Tracks(ii).Dest_valid = false;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_PopulationNbr
        function DropDown_PopulationNbr_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    app.updateTrackPropPopups
                end
                app.updateTable;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_TrackProp1
        function DropDown_TrackProp1_Callback(app, event)
            
            try
                app.updateTable;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_TrackProp2
        function DropDown_TrackProp2_Callback(app, event)

            try
                app.updateTable;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_colormap
        function DropDown_colormap_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    app.Tracks = app.Tracks.setArrayProperty('colormap',app.DropDown_colormap.Value);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_loadDest
        function DropDown_loadDest_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    if app.DropDown_loadDest.Value == 1
                        app.Tracks(ii).Dest = []; % if 'None'
                        app.DropDown_Dmodel.Items = {'No model available'};
                    else
                        if isempty(app.Tracks(ii).Dest) || ~strcmp(app.DropDown_loadDest.Items{app.DropDown_loadDest.Value},app.Tracks(ii).Dest.estName)
                            % model is not yet loaded (normal case)
                            app.Tracks(ii).Dest = Dest.(app.DropDown_loadDest.Items{app.DropDown_loadDest.Value});
                            app.DropDown_Dmodel.Items = app.Tracks(ii).Dest.supportedModels;
                        else
                            % model is already present in tracks (due to
                            % save load)
                            % load models in menu
                            app.DropDown_Dmodel.Items = app.Tracks(ii).Dest.supportedModels;
                            % select right model
                            app.DropDown_Dmodel.Value = app.Tracks(ii).Dest.DModel.name;
                        end
                        
                    end
                    app.Tracks(ii).Dest_valid = false;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_DestOptions
        function button_DestOptions_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    if app.DropDown_loadDest.Value > 1
                        app.Tracks(ii).Dest = app.Tracks(ii).Dest.inputdlgVars;
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_DestPlots
        function button_DestPlots_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    ID = get(app.DropDown_TrackNbr,'Value');
                    if app.DropDown_loadDest.Value > 1
                        app.Tracks(ii).Dest.plotdlg(app.Tracks(ii),ID); % plot menu
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_PlotTrackPropCorr
        function button_PlotTrackPropCorr_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    txt1 = app.DropDown_TrackProp1.ItemsData;
                    txt1name = app.DropDown_TrackProp1.Items;
                    idx1 = app.DropDown_TrackProp1.Value;
                    txt2 = app.DropDown_TrackProp2.ItemsData;
                    txt2name = app.DropDown_TrackProp2.Items;
                    idx2 = app.DropDown_TrackProp2.Value;
                    figure
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotPropCorrelation([],idx1,idx2);
                        title(['Correlation of property ' txt1name{strcmp(txt1,idx1)} ' and ' txt2name{strcmp(txt2,idx2)} ' (all populations)'])
                    else
                        ii = app.DropDown_PopulationNbr.Value;
                        app.Tracks(ii).plotPropCorrelation([],idx1,idx2);
                        title(['Correlation of property ' txt1name{strcmp(txt1,idx1)} ' and ' txt2name{strcmp(txt2,idx2)} ' (population ' num2str(ii) ')'])
                        legend off
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_PlotTracks
        function button_PlotTracks_Callback(app, event)
            
            try
                % ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    figure
                    if app.checkbox_plotallpopulations.Value
                        [~,h] = app.Tracks.plotTracks;
                        title('Tracks (all populations)')
                    else
                        ii = app.DropDown_PopulationNbr.Value;
                        [~,h] = app.Tracks(ii).plotTracks;
                        title(['Tracks (population ' num2str(ii) ')'])
                        legend off
                    end
            
                    app.button_PlotTracks.UserData = h; % store handle of last axis
                end
                app.setTrackLabels; % sets the label
                app.checkbox_plotTracksLabel_Callback([]); % toggles the visibility
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_applyfiltertoall
        function button_applyfiltertoall_Callback(app, event)
            
            try
                S = app.button_plotDtree.UserData; % struct
                ii = S.popNbr; % use population number when selected
                if ~isempty(app.Tracks)
                    filterIDX = get(app.text_propThList,'UserData');
                    if ~isempty(filterIDX)
                        [app.Tracks(numel(app.Tracks)+1), app.Tracks(ii)] = app.Tracks(ii).segment(filterIDX); % append the smaller than threshold to the population
                        app.updateTable;
                        sprintf('Filter has been applied to all\n%s',app.text_propThList.Text{:});
                        sprintf('Population with \n%s',app.text_propThList.Text{:});
                        app.text_propThList.UserData = '';
                        app.text_propThList.Text = 'No property filter selected';
                        app.DropDown_TrackNbr.Value = 1; % reset app.Tracks display to first to prevent invalid values
                        app.updateTrackPopulationPopups;
                        app.Tracks(1).segTree = app.Tracks(1).segTree.add(ii,S.label{1},S.label{2});
                        app.Tracks = app.Tracks.setArrayProperty('segTree',app.Tracks(1).segTree);
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_boxplot1stprop
        function button_boxplot1stprop_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    txt1 = app.DropDown_TrackProp1.ItemsData;
                    txt1name = app.DropDown_TrackProp1.Items;
                    idx1 = app.DropDown_TrackProp1.Value;
                    figure
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotBoxProp([],idx1);
                        title(['Box plot of property ' txt1name{strcmp(txt1,idx1)} ' (all populations)'])
                    else
                        ii = app.DropDown_PopulationNbr.Value;
                        app.Tracks(ii).plotBoxProp([], idx1);
                        title(['Box plot of property ' txt1name{strcmp(txt1,idx1)} ' (population ' num2str(ii) ')'])
                        legend off
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_computediffusion
        function button_computediffusion_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    disp('Computing diffusion...')
                    if app.checkbox_computeDallpop.Value
                        app.Tracks = app.Tracks.computeDiffusion;
                    else
                        app.Tracks(ii) = app.Tracks(ii).computeDiffusion;
                    end
                    app.updateTrackPropPopups
                    app.Tracks.reportDest;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_computeprops
        function button_computeprops_Callback(app, event)
            
            try
            
                if ~isempty(app.Tracks)
                    if min(vertcat(app.Tracks.nTrackPoints)) < 3
                        errordlg({'Minimum number of points in track is 3','Please apply thresholding'})
                        return
                    end
                    
                    % --- compute properties
%                     app.Tracks = app.Tracks.setArrayProperty('doFeatures',app.doFeatures); % set features to be computed
                    app.Tracks = app.Tracks.computeFeatures(false,app.doFeatures,app.filepathFeatures);
                    wb = waitbar(0,'Computing other','Name','Computing track properties');
                    waitbar(0.5,wb,'Computing MSD')
                    app.Tracks = app.Tracks.computeMSD;
                    waitbar(0.9,wb,'Updating GUI')
                    % --- update GUI
                    app.updateTrackPropPopups;
                    app.updateTable;
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_copytable
        function button_copytable_Callback(app, event)
            
            try
                tableData = app.table1.Data;
                if ~isempty(tableData)
                    app.f_clipboard(tableData)
                    disp('Table data copied to clipboard')
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_delCurrentPop
        function button_delCurrentPop_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    if numel(app.Tracks) == 1
                        errordlg('Cannot delete last population')
                        return
                    end
                    app.Tracks(ii) = [];
                    app.updateTrackPopulationPopups
                    app.updateTable;
                    app.Tracks(1).segTree = app.Tracks(1).segTree.remove(ii);
                    app.Tracks = app.Tracks.setArrayProperty('segTree',app.Tracks(1).segTree);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_histogram1stprop
        function button_histogram1stprop_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    txt1 = app.DropDown_TrackProp1.ItemsData;
                    txt1name = app.DropDown_TrackProp1.Items;
                    idx1 = app.DropDown_TrackProp1.Value;
                    if app.checkbox_probabilityhist.Value
                        normalization = 'probability';
                    else
                        normalization = 'count';
                    end
                    figure
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotHistProp([], idx1, [], app.checkbox_xlog.Value, normalization);
                        title(['Histogram of property ' txt1name{strcmp(txt1,idx1)} ' (all populations)'])
                    else
                        ii = app.DropDown_PopulationNbr.Value;
                        app.Tracks(ii).plotHistProp([], idx1, [], app.checkbox_xlog.Value, normalization);
                        title(['Histogram of property ' txt1name{strcmp(txt1,idx1)} ' (population ' num2str(ii) ')'])
                        legend off
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotDtree
        function button_plotDtree_Callback(app, event)

            try
                figure
                app.Tracks(1).segTree.plot;
                title('Decision tree')
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotMSDpop
        function button_plotMSDpop_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    figure
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotMeanMSD([],false)
                        title('Population MSD curves (all populations)')
                        title('All MSD curves');
                    else
                        app.Tracks(ii).plotMeanMSD([],false)
                        title(['Population MSD curves ' num2str(ii)]);
                    end
                    grid on;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotRemainingMSDs
        function button_plotRemainingMSDs_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    filterIDX = get(app.text_propThList,'UserData');
                    if ~isempty(filterIDX)
                        Napp = numel(app.Tracks)+1;
                        [TrackP(2), TrackP(1)] = app.Tracks(ii).segment(filterIDX);
                        figure;
                        TrackP.plotMSD;
                        % manually set legend for exceptional case
                        l = legend;
                        l.String = {['Population ' num2str(ii)],['Population ' num2str(Napp)]};
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotRemainingTracks
        function button_plotRemainingTracks_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    filterIDX = get(app.text_propThList,'UserData');
                    if ~isempty(filterIDX)
                        Napp = numel(app.Tracks)+1;
                        [TrackP(2), TrackP(1)] = app.Tracks(ii).segment(filterIDX);
                        figure;
                        TrackP.plotTracks;
                        % manually set legend for exceptional case
                        l = legend;
                        l.String = {['Population ' num2str(ii)],['Population ' num2str(Napp)]};
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotSD
        function button_plotSD_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    delayIdx = str2num(app.DelayindexEditField.Value);
                    if ~isnan(delayIdx)
                        figure
                        if app.checkbox_plotallpopulations.Value
                            app.Tracks.plotdR([], [], delayIdx);
                            title('Histogram of property displacements (all populations)')
                        else
                            ii = app.DropDown_PopulationNbr.Value;
                            app.Tracks(ii).plotdR([], [], delayIdx);
                            title(['Histogram of displacements (population ' num2str(ii) ')'])
                            legend off
                        end
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotmsd
        function button_plotmsd_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    figure
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotMSD
                        title('All MSD curves');
                    else
                        app.Tracks(ii).plotMSD
                        title(['All MSD curves population ' num2str(ii)]);
                    end
                    grid on;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotselectedmsd
        function button_plotselectedmsd_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    ID = get(app.DropDown_TrackNbr,'Value');
                    figure
                    app.Tracks(ii).plotMSD([],false,ID)
                    title(['MSD curve track ' num2str(ID) ' of population ' num2str(ii)])
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotselectedtrack
        function button_plotselectedtrack_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    ID = get(app.DropDown_TrackNbr,'Value');
                    figure
                    app.Tracks(ii).plotTracks([],ID);
                    title(['Track ' num2str(ID) ' of population ' num2str(ii)]);
                %     app.Tracks(ii).plotTrackAndProperties([],ID);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_reportDest
        function button_reportDest_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    app.Tracks.reportDest
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_resetFilter
        function button_resetFilter_Callback(app, event)
            
            try
                app.text_propThList.UserData = '';
                app.text_propThList.Text = 'No property filter selected';
            catch err
                app.errorBox(err)
            end
        end

        % Callback function
        function button_saveTracks_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
            %         assignin('base','app.Tracks',Track)
                    [fname,pname]=uiputfile('.mat','Save Tracks');
                    wb = waitbar(0.5,'Saving DiffusionLab session...','Name','Please wait');
                    save(fullfile(pname,fname),'app.Tracks','-v7.3');
            %         app.Tracks.saveAsStruct(fullfile(pname,fname))
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_setcurrentDesttoall
        function button_setcurrentDesttoall_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    [app.Tracks.Dest] = deal(app.Tracks(ii).Dest);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_useAsFilter
        function button_useAsFilter_Callback(app, event)
            
            try
                ii = app.DropDown_PopulationNbr.Value;
                if ~isempty(app.Tracks)
                    th = app.PropertythresholdEditField.Value;
                    txt1 = app.DropDown_TrackProp1.ItemsData;
                    txt1name = app.DropDown_TrackProp1.Items;
                    idx1 = app.DropDown_TrackProp1.Value;
                    [TrackProp,unit] = app.Tracks(ii).getFeature(idx1,true);
                    [uF, uL] = app.Tracks(ii).getUnitFactor(unit); % convert into right units
                    TrackProp = TrackProp .* uF;
                    filterIDX = TrackProp >= th;
                    newtxt{1} = ['Population ' num2str(ii)];
                    newtxt{2} = txt1name{strcmp(txt1,idx1)};
                    newtxt{3}=sprintf('Population %i will have values < %.3f\nPopulation %i will have values >= %.3f',ii,th,numel(app.Tracks)+1,th);
                    newtxt{4} = sprintf('Number of tracks population\n%i/%i: %i/%i',ii,numel(app.Tracks)+1,sum(~filterIDX),sum(filterIDX));
                    app.text_propThList.Text =  newtxt;
        
                    % store filter settings
                    app.text_propThList.UserData = filterIDX;
                    app.button_plotDtree.UserData.label = {[txt1name{strcmp(txt1,idx1)} ' < ' num2str(th) ' ' uL] [txt1name{strcmp(txt1,idx1)} ' >= ' num2str(th) ' ' uL]};
                    app.button_plotDtree.UserData.popNbr = ii;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_voronoidiag
        function button_voronoidiag_Callback(app, event)
            
            try
                if ~isempty(app.Tracks)
                    txt1 = app.DropDown_TrackProp1.ItemsData;
                    txt1name = app.DropDown_TrackProp1.Items;
                    idx1 = app.DropDown_TrackProp1.Value;
                    noEdgeFlag = get(app.checkbox_NoEdges,'Value');
                    noCoMFlag = get(app.checkbox_NoCoM,'Value');
                    figure
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotVoronoiDiag([],idx1,noEdgeFlag,noCoMFlag)
                        title('Voronoi diagram (all populations)')
                    else
                        ii = app.DropDown_PopulationNbr.Value;
                        app.Tracks(ii).plotVoronoiDiag([],idx1,noEdgeFlag,noCoMFlag)
                        title(['Voronoi diagram of property ' txt1name{strcmp(txt1,idx1)} ' (population ' num2str(ii) ')'])
                        legend off
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: PixelsizenmEditField
        function PixelsizenmEditFieldValueChanged(app, event)
            
            try
                if ~isempty(app.Tracks)
                    pxsize = app.PixelsizenmEditField.Value;
                    if ~isnan(pxsize)
                        u = symunit;
                        app.Tracks = app.Tracks.setUnit('pixelsize',pxsize * u.nm);
                    end
                end
                app.PixelsizenmEditField.FontColor = [0 0 0]; % black
            catch err
                app.errorBox(err)
                app.PixelsizenmEditField.FontColor = [1 0 0]; % red
            end
        end

        % Value changed function: FrametimesEditField
        function FrametimesEditFieldValueChanged(app, event)
            try
                if ~isempty(app.Tracks)
                    dt = app.FrametimesEditField.Value;
                    u = symunit;
                    % we set the unit; dt stored in tracks is a fundamental property of the time series and is set during import
                    app.Tracks = app.Tracks.setUnit('dt',dt * u.s);
                end
                app.FrametimesEditField.FontColor = [0 0 0]; % black
            catch err
                app.errorBox(err)
                app.FrametimesEditField.FontColor = [1 0 0]; % red
            end
        end

        % Value changed function: ExposuretimesEditField
        function ExposuretimesEditFieldValueChanged(app, event)
            try
                if ~isempty(app.Tracks)
                    dte = app.ExposuretimesEditField.Value;
                    dt = app.Tracks.getUnit('dt');
                    if app.ExposuretimesEditField.Value > app.FrametimesEditField.Value
                        error('Exposure time cannot be longer than the frame time.')
                    end
                    app.Tracks = app.Tracks.setArrayProperty('dte',dte/app.Tracks(1).getUnitFactor(dt)); % have to set dte in reduced units
                end
                app.ExposuretimesEditField.FontColor = [0 0 0]; % black
            catch err
                app.errorBox(err)
                app.ExposuretimesEditField.FontColor = [1 0 0]; % red
            end
        end

        % Value changed function: LinethicknessEditField
        function LinethicknessEditFieldValueChanged(app, event)

            try
                if ~isempty(app.Tracks)
                    app.Tracks = app.Tracks.setArrayProperty('lineWidth', app.LinethicknessEditField.Value);
                end
                app.LinethicknessEditField.FontColor = [0 0 0]; % black
            catch err
                app.errorBox(err)
                app.LinethicknessEditField.FontColor = [1 0 0]; % red
            end
        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
            if strcmp(app.DebugMenu.Checked,'on')
                app.DebugMenu.Checked = 'off';
            else
                app.DebugMenu.Checked = 'on';
            end
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            try
                
                % make checked logical
                if strcmp(app.DebugMenu.Checked,'on')
                    debugFlag = 1;
                else
                    debugFlag = 0;
                end
                
                % run GUI
                ExportFigures_App(app,debugFlag)
            catch err
                app.errorBox(err)
            end
            
        end

        % Menu selected function: CloseallopenfiguresMenu
        function CloseallopenfiguresMenuSelected(app, event)
            try
                figHandles = findobj('Type','figure');
                n_openFigs = size(figHandles);
                for ii=1:n_openFigs
                    if ~strcmp(figHandles(ii).Name,'DiffusionLab')
                        close(figHandles(ii));
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            try
                if ~isempty(app.Tracks)
            %         assignin('base','app.Tracks',Track)
                    [fname,pname]=uiputfile('.mat','Save Tracks');
                    wb = waitbar(0.5,'Saving DiffusionLab session...','Name','Please wait');
                    Tracks = app.Tracks; % save variable
                    save(fullfile(pname,fname),'Tracks','-v7.3');
            %         app.Tracks.saveAsStruct(fullfile(pname,fname))
                    close(wb)
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            
            try
                % --- load Track
                [fname,pname,idx] =uigetfile( ...
                    {'*.mat','MATLAB Files (*.mat)'},...
                   'Please select the file containing the DiffusionLab session');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        in = load(fullfile(pname,fname)); % Loads 'Track'
                        fn = fieldnames(in);
                        nTracksObj = 0;
                        for ii = 1:numel(fn)
                            if isa(in.(fn{ii}),'tracks')
                                Track = in.(fn{ii});
                                nTracksObj = nTracksObj + 1;
                            end
                        end
                        if nTracksObj ~= 1
                            error('Expected one ''tracks'' object but found %d instead.',...
                                nTracksObj);
                        end
                        if isstruct(Track)
                            Track = tracks.open(fullfile(pname,fname),'tracks');
            %                 Track = tracks.importTrackStruct(Track,1,1);u.m,u.s); % functionality to import Florian's track structure
                        elseif isobject(Track)
                            % do nothing
                        else
                            error('Tracks could not be loaded...')
                        end
                        app.Tracks = Track;
                end
                
                % Make sure there is at least one property computed
                app.Tracks = app.Tracks.computeFeatures(true,'nTrackPoints');
            
                % --- set GUI
                app.initTracks;
                app.updateTrackPopulationPopups; % also calls updateTrackPropPopups
                app.updateTable;
            
                disp('Done');
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: DoMMenu
        function DoMMenuSelected(app, event)
            
            try
                % --- load Track
                [fname,pname,idx] =uigetfile( ...
                    {'*.csv','DoM results table (*.csv)'},...
                   'Please select the file containing the tracks');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        app.Tracks = tracks.importDoM(fullfile(pname,fname),true,1,1); %u.m,u.s); % dont use, makes saving orders of magnitude slower!!!
                end
                
                % Make sure there is at least one property computed
                app.Tracks = app.Tracks.computeFeatures(true,'nTrackPoints');
            
                % --- set GUI
                app.initTracks;
                app.updateTrackPopulationPopups; % also calls updateTrackPropPopups
                app.updateTable;
            
                disp('Done');
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: LocalizerMenu
        function LocalizerMenuSelected(app, event)
            
            try
                % --- load Track
                [fname,pname,idx] =uigetfile( ...
                    {'*.txt','Igor Pro text file (*.txt)'},...
                   'Please select the file containing the tracks');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        app.Tracks = tracks.importLocalizer(fullfile(pname,fname),1,1); %u.m,u.s); % dont use, makes saving orders of magnitude slower!!!
                end
                
                % Make sure there is at least one property computed
                app.Tracks = app.Tracks.computeFeatures(true,'nTrackPoints');
            
                % --- set GUI
                app.initTracks;
                app.updateTrackPopulationPopups; % also calls updateTrackPropPopups
                app.updateTable;
            
                disp('Done');
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DelayindexEditField
        function DelayindexEditFieldValueChanged(app, event)
            try
                validateattributes(str2num(app.DelayindexEditField.Value),{'numeric'},{'vector','nonnegative','integer'})
                app.DelayindexEditField.FontColor = [0 0 0]; % black
            catch err
                app.errorBox(err)
                app.DelayindexEditField.FontColor = [1 0 0]; % red
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            % Create the message dialog box, including the custom icon.
            text = sprintf('DiffusionLab v. %s\nContact: j.j.e.maris@uu.nl\nJ.J. Erik Maris & Florian Meirer\n',app.version);
            msgbox(text,'About');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: TrackstoworkspaceMenu
        function TrackstoworkspaceMenuSelected(app, event)
            assignin("base","Tracks",app.Tracks)
        end

        % Button pushed function: PlotsquareddisplacementCPDButton
        function PlotsquareddisplacementCPDButtonButtonPushed(app, event)
                        
            try
                if ~isempty(app.Tracks)
                    delayIdx = str2num(app.DelayindexEditField.Value);
                    if ~isnan(delayIdx)
                        figure
                        if app.checkbox_plotallpopulations.Value
                            app.Tracks.plotdR2CPD([], [], delayIdx);
                            title('Histogram of property squared displacements (all populations)')
                        else
                            ii = app.DropDown_PopulationNbr.Value;
                            app.Tracks(ii).plotdR2CPD([], [], delayIdx);
                            title(['Histogram of squared displacements (population ' num2str(ii) ')'])
                            legend off
                        end
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: LengthMenu
        function LengthMenuSelected(app, event)
            try
                % available options
                fn = {'nm','um','mm','cm','m'};
                
                % dialog
                [idx,tf] = listdlg('PromptString',{'Please select the length unit:'},...
                    'SelectionMode','single','ListString',fn);
                
                % user pressed cancel
                if ~tf
                    return
                end
                
                % replace and save
                app.Tracks = app.Tracks.subsUnitSystem(fn{idx});
                
                % update table with new units
                updateTable(app)
                
            catch err
                app.errorBox(err)
            end
            
        end

        % Menu selected function: TimeMenu
        function TimeMenuSelected(app, event)
            try
                % available options
                fn = {'us','ms','s','min','h'};
                
                % dialog
                [idx,tf] = listdlg('PromptString',{'Please select the time unit:'},...
                    'SelectionMode','single','ListString',fn);
                
                % user pressed cancel
                if ~tf
                    return
                end
                
                % replace and save
                app.Tracks = app.Tracks.subsUnitSystem(fn{idx});
                
                % update table with new units
                updateTable(app)
                
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PropertiestotableMenu
        function PropertiestotableMenuSelected(app, event)
            try    
                if ~isempty(app.Tracks)
                    [fname,pname,idx] = uiputfile( ...
                        {'*.txt', 'Text file (*.txt)';...
                         '*.dat','Generic data file (*.dat)';...
                         '*.csv','Comma-separated values (*.csv)';...
                         '*.xlsx','Microsoft Excel (*.xlsx)';...
                         '*.mat','MATLAB File (*.mat)';
                         '*.*',  'All Files (*.*)'}, 'Save properties');
                    if idx == 0
                        return % user pressed cancel
                    elseif idx == 5 || idx == 6
                        table = app.Tracks.exportPropertyTable;
                        save(fullfile(pname,fname),'table','-v7.3');
                    else
                        table = app.Tracks.exportPropertyTable;
                        writetable(table,fullfile(pname,fname))
                    end
                    disp('Saved property table to:')
                    disp(fullfile(pname,fname))
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ResettoSIunitsMenu
        function ResettoSIunitsMenuSelected(app, event)
            try
                if ~isempty(app.Tracks)
                    % resets unit system to SI
                    app.Tracks = app.Tracks.resetUnitSystem;
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: MATLABClassificationLearnerMenu
        function MATLABClassificationLearnerMenuSelected(app, event)
            classificationLearner
        end

        % Menu selected function: SegmentwithmodelCLMenu
        function SegmentwithmodelCLMenuSelected(app, event)
            try    
                if ~isempty(app.Tracks)
                    [fname,pname,idx] = uigetfile( ...
                        {'*.mat','MATLAB File (*.mat)';
                         '*.*',  'All Files (*.*)'}, 'Open model');
                    if idx == 0
                        return % user pressed cancel
                    else
                        in = open(fullfile(pname,fname));
                        fn = fieldnames(in);
                        nStructObj = 0;
                        for ii = 1:numel(fn)
                            if isstruct(in.(fn{ii})')
                                model = in.(fn{ii});
                                nStructObj = nStructObj + 1;
                            end
                        end
                        if nStructObj ~= 1
                            error('Expected one structure, but found %d instead.',...
                                nStructObj);
                        end
                    end
                    % no support for segmentation tree yet..
                    newTracks = app.Tracks(app.DropDown_PopulationNbr.Value).segmentCLmodel(model);
                    app.Tracks(app.DropDown_PopulationNbr.Value) = newTracks(1);
                    if numel(newTracks) > 1
                        newTracks = newTracks(2:end);
                        app.Tracks = [app.Tracks(:); newTracks(:)];
                    end
                    app.updateTable;
                    app.DropDown_TrackNbr.Value = 1; % reset app.Tracks display to first to prevent invalid values
                    app.updateTrackPopulationPopups;
                    disp('Segmentation successfull')
%                     app.Tracks(1).segTree = app.Tracks(1).segTree.add(ii,S.label{1},S.label{2});
%                     app.Tracks = app.Tracks.setArrayProperty('segTree',app.Tracks(1).segTree);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: ClassificationTrainerMenu
        function ClassificationTrainerMenuSelected(app, event)
            try    
                % make checked logical
                if strcmp(app.DebugMenu.Checked,'on')
                    debugFlag = 1;
                else
                    debugFlag = 0;
                end
                f = ClassificationTrainer_App(app,app.Tracks(app.DropDown_PopulationNbr.Value),app.doFeatures,debugFlag);
                app.figure1.Visible = 'off';
                uiwait(f.figure1)
                app.updateTable;
                app.DropDown_TrackNbr.Value = 1; % reset app.Tracks display to first to prevent invalid values
                app.updateTrackPopulationPopups;
                app.figure1.Visible = 'on';
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_plotaptialmaphexgrid
        function button_plotaptialmaphexgridButtonPushed(app, event)
            try    
                if ~isempty(app.Tracks)
                    txt1 = app.DropDown_TrackProp1.ItemsData;
                    idx1 = app.DropDown_TrackProp1.Value;
                    binsize = app.BinsizenmEditField.Value;
                    u = symunit;
                    binsize = double(separateUnits(rewrite(binsize .* u.nm,app.Tracks(1).unitSystem)));
                    logFlag = app.LogarithmicCheckBox.Value;
                    figure
                    app.Tracks.plotHexDiag([],binsize,txt1{idx1},[],logFlag,false);
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            try 
                web('https://diffusionlab.readthedocs.io/en/latest/index.html') % open docs 
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_delCurrentPop_2
        function button_delCurrentPop_2ButtonPushed(app, event)
            try
                if ~isempty(app.Tracks)
                    % get popluation numbers
                    popNr1 = app.DropDown_PopulationNbr.ItemsData(app.DropDown_PopulationNbr.Value);
                    popNr2 = app.DropDown.ItemsData(app.DropDown.Value);
                    % check for consistency
                    if popNr1 == popNr2
                        error('Cannot merge a population with itself.')
                    end
                    % Are you sure
                    quest = {'Merging results in the loss of Diffusion Estimator (settings).','Would you like to proceed?'};
                    answer = questdlg(quest,'Boundary Condition','Yes','No','No');
                    switch answer
                        case 'Yes'
                            % do nothing
                        case 'No'
                            return
                    end
                    % merge tracks
                    t_merge = app.Tracks([popNr1 popNr2]);
                    t_merge = t_merge.merge(1,false);
                    app.Tracks(popNr1) = t_merge;
                    clear t_merge
                    app.Tracks(popNr2) = [];
                    % update GUI
                    app.DropDown_TrackNbr.Value = 1; % reset app.Tracks display to first to prevent invalid values
                    app.DropDown.Value = 1; % reset app.Tracks display to first to prevent invalid values
                    app.updateTrackPopulationPopups;
                    % update segTree
                    app.Tracks(1).segTree = app.Tracks(1).segTree.merge(popNr1,popNr2);
                    app.Tracks = app.Tracks.setArrayProperty('segTree',app.Tracks(1).segTree);
                    
                end
            
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: COMSOL3DMenu
        function COMSOL3DMenuSelected(app, event)
            try
                % --- load Track
                [fname,pname,idx] =uigetfile( ...
                    {'*.txt','COMSOL output (*.csv)'},...
                   'Please select the file containing the tracks');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        app.Tracks = tracks.importCOMSOL(fullfile(pname,fname),3,1,1); %u.m,u.s); % dont use, makes saving orders of magnitude slower!!!
                end
                
                % Make sure there is at least one property computed
                app.Tracks = app.Tracks.computeFeatures(true,'nTrackPoints');
            
                % --- set GUI
                app.initTracks;
                app.updateTrackPopulationPopups; % also calls updateTrackPropPopups
                app.updateTable;
            
                disp('Done');
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: COMSOL2DMenu
        function COMSOL2DMenuSelected(app, event)
            try
                % --- load Track
                [fname,pname,idx] =uigetfile( ...
                    {'*.txt','COMSOL output (*.csv)'},...
                   'Please select the file containing the tracks');
            
            %     u = symunit;
                switch idx
                    case 0 % cancel
                        return
                    case 1
                        fprintf('Loading %s\n', fullfile(pname,fname));
                        app.Tracks = tracks.importCOMSOL(fullfile(pname,fname),2,1,1); %u.m,u.s); % dont use, makes saving orders of magnitude slower!!!
                end
                
                % Make sure there is at least one property computed
                app.Tracks = app.Tracks.computeFeatures(true,'nTrackPoints');
            
                % --- set GUI
                app.initTracks;
                app.updateTrackPopulationPopups; % also calls updateTrackPropPopups
                app.updateTable;
            
                disp('Done');
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: TrackMSDstofileMenu
        function TrackMSDstofileMenuSelected(app, event)
            try    
                if ~isempty(app.Tracks)
                    [fname,pname,idx] = uiputfile( ...
                        {'*.xlsx','Microsoft Excel (*.xlsx)';...
                         '*.xls','Microsoft Excel (*.xls)';...
                         '*.*',  'All Files (*.*)'}, 'Save MSDs');
                    if idx == 0
                        return % user pressed cancel
                    else
                        disp('Saving MSDs...')
                        app.Tracks.exportMSD(fullfile(pname,fname));
                    end
                    disp('Saved MSDs to:')
                    disp(fullfile(pname,fname))
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: PopulationMSDstofileMenu
        function PopulationMSDstofileMenuSelected(app, event)
            try    
                if ~isempty(app.Tracks)
                    [fname,pname,idx] = uiputfile( ...
                        {'*.xlsx','Microsoft Excel (*.xlsx)';...
                         '*.xls','Microsoft Excel (*.xls)';...
                         '*.*',  'All Files (*.*)'}, 'Save MSDs');
                    if idx == 0
                        return % user pressed cancel
                    else
                        disp('Saving MSDs...')
                        app.Tracks.exportMeanMSD(fullfile(pname,fname));
                    end
                    disp('Saved Population MSDs to:')
                    disp(fullfile(pname,fname))
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: BiplotPCAButton
        function BiplotPCAButtonPushed(app, event)
            try
                if ~isempty(app.Tracks)
                    if app.checkbox_plotallpopulations.Value
                        app.Tracks.plotPCAScorePlot([],'GUI');
                        title(['Score plot (all populations)'])
                    else
                        ii = app.DropDown_PopulationNbr.Value;
                        app.Tracks(ii).plotPCAScorePlot([],'GUI');
                        title(['Score plot (population ' num2str(ii) ')'])
                        legend off
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Menu selected function: LoadfeaturescriptMenu
        function LoadfeaturescriptMenuSelected(app, event)
            try
                [file,path,idx] = uigetfile({'*.m';'*.*'},...
                          'Please select one or more feature script(s)','MultiSelect', 'on');
                if idx == 0
                    msgbox('User pressed cancel. Saved filepaths have been deleted.')
                    app.filepathFeatures = [];
                else
                    app.filepathFeatures = fullfile(path,file);
                    fprintf('Loaded feature scripts:\n')
                    if iscell(app.filepathFeatures)
                        for ii = 1:numel(app.filepathFeatures)
                            fprintf('%s\n',app.filepathFeatures{ii})
                        end
                    else
                        fprintf('%s\n',app.filepathFeatures)
                    end
                end
                
            catch err
                app.errorBox(err)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DiffusionLab';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadfeaturescriptMenu = uimenu(app.FileMenu);
            app.LoadfeaturescriptMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfeaturescriptMenuSelected, true);
            app.LoadfeaturescriptMenu.Text = 'Load feature script';
            app.ImporttracksMenu = uimenu(app.FileMenu);
            app.ImporttracksMenu.Text = 'Import tracks';
            app.DoMMenu = uimenu(app.ImporttracksMenu);
            app.DoMMenu.MenuSelectedFcn = createCallbackFcn(app, @DoMMenuSelected, true);
            app.DoMMenu.Text = 'DoM';
            app.LocalizerMenu = uimenu(app.ImporttracksMenu);
            app.LocalizerMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalizerMenuSelected, true);
            app.LocalizerMenu.Text = 'Localizer';
            app.COMSOL2DMenu = uimenu(app.ImporttracksMenu);
            app.COMSOL2DMenu.MenuSelectedFcn = createCallbackFcn(app, @COMSOL2DMenuSelected, true);
            app.COMSOL2DMenu.Text = 'COMSOL 2D';
            app.COMSOL3DMenu = uimenu(app.ImporttracksMenu);
            app.COMSOL3DMenu.MenuSelectedFcn = createCallbackFcn(app, @COMSOL3DMenuSelected, true);
            app.COMSOL3DMenu.Text = 'COMSOL 3D';
            app.ExportMenu_2 = uimenu(app.FileMenu);
            app.ExportMenu_2.Text = 'Export';
            app.FigureMenu = uimenu(app.ExportMenu_2);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.TrackstoworkspaceMenu = uimenu(app.ExportMenu_2);
            app.TrackstoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackstoworkspaceMenuSelected, true);
            app.TrackstoworkspaceMenu.Text = 'Tracks to workspace';
            app.PropertiestotableMenu = uimenu(app.ExportMenu_2);
            app.PropertiestotableMenu.MenuSelectedFcn = createCallbackFcn(app, @PropertiestotableMenuSelected, true);
            app.PropertiestotableMenu.Text = 'Properties to table';
            app.TrackMSDstofileMenu = uimenu(app.ExportMenu_2);
            app.TrackMSDstofileMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackMSDstofileMenuSelected, true);
            app.TrackMSDstofileMenu.Text = 'Track MSDs to file';
            app.PopulationMSDstofileMenu = uimenu(app.ExportMenu_2);
            app.PopulationMSDstofileMenu.MenuSelectedFcn = createCallbackFcn(app, @PopulationMSDstofileMenuSelected, true);
            app.PopulationMSDstofileMenu.Text = 'Population MSDs to file';
            app.CloseallopenfiguresMenu = uimenu(app.FileMenu);
            app.CloseallopenfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallopenfiguresMenuSelected, true);
            app.CloseallopenfiguresMenu.Separator = 'on';
            app.CloseallopenfiguresMenu.Text = 'Close all open figures';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ClassificationMenu = uimenu(app.figure1);
            app.ClassificationMenu.Text = 'Classification';
            app.ClassificationTrainerMenu = uimenu(app.ClassificationMenu);
            app.ClassificationTrainerMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassificationTrainerMenuSelected, true);
            app.ClassificationTrainerMenu.Text = 'Classification Trainer';
            app.SegmentwithmodelCLMenu = uimenu(app.ClassificationMenu);
            app.SegmentwithmodelCLMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentwithmodelCLMenuSelected, true);
            app.SegmentwithmodelCLMenu.Text = 'Segment with model CL';
            app.MATLABClassificationLearnerMenu = uimenu(app.ClassificationMenu);
            app.MATLABClassificationLearnerMenu.MenuSelectedFcn = createCallbackFcn(app, @MATLABClassificationLearnerMenuSelected, true);
            app.MATLABClassificationLearnerMenu.Text = 'MATLAB Classification Learner';
            app.ViewMenu = uimenu(app.figure1);
            app.ViewMenu.Text = 'View';
            app.UnitsMenu = uimenu(app.ViewMenu);
            app.UnitsMenu.Text = 'Units';
            app.LengthMenu = uimenu(app.UnitsMenu);
            app.LengthMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthMenuSelected, true);
            app.LengthMenu.Text = 'Length';
            app.TimeMenu = uimenu(app.UnitsMenu);
            app.TimeMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeMenuSelected, true);
            app.TimeMenu.Text = 'Time';
            app.ResettoSIunitsMenu = uimenu(app.UnitsMenu);
            app.ResettoSIunitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoSIunitsMenuSelected, true);
            app.ResettoSIunitsMenu.Text = 'Reset to SI units';
            app.HelpMenu = uimenu(app.figure1);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.DebugMenu = uimenu(app.HelpMenu);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Separator = 'on';
            app.DebugMenu.Text = 'Debug';
            app.panel_plotproperties = uipanel(app.figure1);
            app.panel_plotproperties.Title = 'Plot properties';
            app.LinethicknessEditFieldLabel = uilabel(app.panel_plotproperties);
            app.LinethicknessEditFieldLabel.Text = 'Line thickness';
            app.LinethicknessEditField = uieditfield(app.panel_plotproperties, 'numeric');
            app.LinethicknessEditField.LowerLimitInclusive = 'off';
            app.LinethicknessEditField.UpperLimitInclusive = 'off';
            app.LinethicknessEditField.Limits = [0 Inf];
            app.LinethicknessEditField.ValueChangedFcn = createCallbackFcn(app, @LinethicknessEditFieldValueChanged, true);
            app.LinethicknessEditField.Value = 1;
            app.button_plotselectedmsd = uibutton(app.panel_plotproperties, 'push');
            app.button_plotselectedmsd.ButtonPushedFcn = createCallbackFcn(app, @button_plotselectedmsd_Callback, true);
            app.button_plotselectedmsd.Text = 'Plot MSD selected track';
            app.button_plotselectedtrack = uibutton(app.panel_plotproperties, 'push');
            app.button_plotselectedtrack.ButtonPushedFcn = createCallbackFcn(app, @button_plotselectedtrack_Callback, true);
            app.button_plotselectedtrack.Text = 'Plot selected track';
            app.DropDown_colormap = uidropdown(app.panel_plotproperties);
            app.DropDown_colormap.Items = {'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag	', 'white'};
            app.DropDown_colormap.ValueChangedFcn = createCallbackFcn(app, @DropDown_colormap_Callback, true);
            app.DropDown_colormap.Value = 'jet';
            app.button_PlotTracks = uibutton(app.panel_plotproperties, 'push');
            app.button_PlotTracks.ButtonPushedFcn = createCallbackFcn(app, @button_PlotTracks_Callback, true);
            app.button_PlotTracks.Text = 'Plot tracks';
            app.checkbox_plotTracksLabel = uicheckbox(app.panel_plotproperties);
            app.checkbox_plotTracksLabel.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotTracksLabel_Callback, true);
            app.checkbox_plotTracksLabel.Text = 'Label';
            app.button_plotmsd = uibutton(app.panel_plotproperties, 'push');
            app.button_plotmsd.ButtonPushedFcn = createCallbackFcn(app, @button_plotmsd_Callback, true);
            app.button_plotmsd.Text = 'Plot MSD all tracks';
            app.button_plotSD = uibutton(app.panel_plotproperties, 'push');
            app.button_plotSD.ButtonPushedFcn = createCallbackFcn(app, @button_plotSD_Callback, true);
            app.button_plotSD.Text = 'Plot displacement histogram';
            app.button_plotMSDpop = uibutton(app.panel_plotproperties, 'push');
            app.button_plotMSDpop.ButtonPushedFcn = createCallbackFcn(app, @button_plotMSDpop_Callback, true);
            app.button_plotMSDpop.Text = 'Plot population MSD';
            app.checkbox_plotallpopulations = uicheckbox(app.panel_plotproperties);
            app.checkbox_plotallpopulations.Text = 'Plot for all populations (opts below)';
            app.DelayindexEditFieldLabel = uilabel(app.panel_plotproperties);
            app.DelayindexEditFieldLabel.Text = 'Delay index';
            app.DelayindexEditField = uieditfield(app.panel_plotproperties, 'text');
            app.DelayindexEditField.ValueChangedFcn = createCallbackFcn(app, @DelayindexEditFieldValueChanged, true);
            app.DelayindexEditField.Value = '1';
            app.PlotsquareddisplacementCPDButton = uibutton(app.panel_plotproperties, 'push');
            app.PlotsquareddisplacementCPDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsquareddisplacementCPDButtonButtonPushed, true);
            app.PlotsquareddisplacementCPDButton.Text = 'Plot squared displacement CPD';
            app.checkbox_NoCoM = uicheckbox(app.panel_plotproperties);
            app.checkbox_NoCoM.Text = 'No centers of mass';
            app.checkbox_xlog = uicheckbox(app.panel_plotproperties);
            app.checkbox_xlog.Text = 'Logarithmic binning';
            app.button_PlotTrackPropCorr = uibutton(app.panel_plotproperties, 'push');
            app.button_PlotTrackPropCorr.ButtonPushedFcn = createCallbackFcn(app, @button_PlotTrackPropCorr_Callback, true);
            app.button_PlotTrackPropCorr.Text = 'Plot track property correlation';
            app.button_voronoidiag = uibutton(app.panel_plotproperties, 'push');
            app.button_voronoidiag.ButtonPushedFcn = createCallbackFcn(app, @button_voronoidiag_Callback, true);
            app.button_voronoidiag.Text = 'Spatial map of 1st property (voronoi)';
            app.checkbox_NoEdges = uicheckbox(app.panel_plotproperties);
            app.checkbox_NoEdges.Text = 'No edges';
            app.button_histogram1stprop = uibutton(app.panel_plotproperties, 'push');
            app.button_histogram1stprop.ButtonPushedFcn = createCallbackFcn(app, @button_histogram1stprop_Callback, true);
            app.button_histogram1stprop.Text = 'Histogram of 1st property';
            app.checkbox_probabilityhist = uicheckbox(app.panel_plotproperties);
            app.checkbox_probabilityhist.Text = 'Probability';
            app.button_boxplot1stprop = uibutton(app.panel_plotproperties, 'push');
            app.button_boxplot1stprop.ButtonPushedFcn = createCallbackFcn(app, @button_boxplot1stprop_Callback, true);
            app.button_boxplot1stprop.Text = 'Box plot of 1st property';
            app.BiplotPCAButton = uibutton(app.panel_plotproperties, 'push');
            app.BiplotPCAButton.ButtonPushedFcn = createCallbackFcn(app, @BiplotPCAButtonPushed, true);
            app.BiplotPCAButton.Text = 'Biplot (PCA)';
            app.panel_Dest = uipanel(app.figure1);
            app.panel_Dest.Title = 'Diffusion estimation';
            app.panel_selection = uipanel(app.figure1);
            app.panel_selection.Title = 'Selection';
            app.panel_computeproperties = uipanel(app.figure1);
            app.panel_computeproperties.Title = 'Compute properties';
            app.button_computeprops = uibutton(app.panel_computeproperties, 'push');
            app.button_computeprops.ButtonPushedFcn = createCallbackFcn(app, @button_computeprops_Callback, true);
            app.button_computeprops.Text = 'Compute track properties';
            app.PixelsizenmEditFieldLabel = uilabel(app.panel_computeproperties);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size (nm)';
            app.PixelsizenmEditField = uieditfield(app.panel_computeproperties, 'numeric');
            app.PixelsizenmEditField.LowerLimitInclusive = 'off';
            app.PixelsizenmEditField.UpperLimitInclusive = 'off';
            app.PixelsizenmEditField.Limits = [0 Inf];
            app.PixelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizenmEditFieldValueChanged, true);
            app.PixelsizenmEditField.Value = 130;
            app.FrametimesEditFieldLabel = uilabel(app.panel_computeproperties);
            app.FrametimesEditFieldLabel.Text = 'Frame time (s)';
            app.FrametimesEditField = uieditfield(app.panel_computeproperties, 'numeric');
            app.FrametimesEditField.LowerLimitInclusive = 'off';
            app.FrametimesEditField.UpperLimitInclusive = 'off';
            app.FrametimesEditField.Limits = [0 Inf];
            app.FrametimesEditField.ValueChangedFcn = createCallbackFcn(app, @FrametimesEditFieldValueChanged, true);
            app.FrametimesEditField.Value = 0.03;
            app.ExposuretimesEditFieldLabel = uilabel(app.panel_computeproperties);
            app.ExposuretimesEditFieldLabel.Text = 'Exposure time (s)';
            app.ExposuretimesEditField = uieditfield(app.panel_computeproperties, 'numeric');
            app.ExposuretimesEditField.UpperLimitInclusive = 'off';
            app.ExposuretimesEditField.Limits = [0 Inf];
            app.ExposuretimesEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimesEditFieldValueChanged, true);
            app.panel_segmentation = uipanel(app.figure1);
            app.panel_segmentation.Title = 'Segmentation';
            app.button_useAsFilter = uibutton(app.panel_segmentation, 'push');
            app.button_useAsFilter.ButtonPushedFcn = createCallbackFcn(app, @button_useAsFilter_Callback, true);
            app.button_useAsFilter.Text = 'Use property 1 as filter';
            app.button_resetFilter = uibutton(app.panel_segmentation, 'push');
            app.button_resetFilter.ButtonPushedFcn = createCallbackFcn(app, @button_resetFilter_Callback, true);
            app.button_resetFilter.Text = 'Reset filter';
            app.button_plotRemainingTracks = uibutton(app.panel_segmentation, 'push');
            app.button_plotRemainingTracks.ButtonPushedFcn = createCallbackFcn(app, @button_plotRemainingTracks_Callback, true);
            app.button_plotRemainingTracks.Text = 'Preview all filtered tracks';
            app.button_plotRemainingMSDs = uibutton(app.panel_segmentation, 'push');
            app.button_plotRemainingMSDs.ButtonPushedFcn = createCallbackFcn(app, @button_plotRemainingMSDs_Callback, true);
            app.button_plotRemainingMSDs.Text = 'Preview all filtered MSDs';
            app.button_applyfiltertoall = uibutton(app.panel_segmentation, 'push');
            app.button_applyfiltertoall.ButtonPushedFcn = createCallbackFcn(app, @button_applyfiltertoall_Callback, true);
            app.button_applyfiltertoall.Text = 'Apply filter to all tracks';
            app.button_delCurrentPop = uibutton(app.panel_segmentation, 'push');
            app.button_delCurrentPop.ButtonPushedFcn = createCallbackFcn(app, @button_delCurrentPop_Callback, true);
            app.button_delCurrentPop.Text = 'Delete current population';
            app.text_propThList = uilabel(app.panel_segmentation);
            app.text_propThList.Text = 'No property filter selected';
            app.button_plotDtree = uibutton(app.panel_segmentation, 'push');
            app.button_plotDtree.ButtonPushedFcn = createCallbackFcn(app, @button_plotDtree_Callback, true);
            app.button_plotDtree.Text = 'Plot decision tree';
            app.PropertythresholdEditFieldLabel = uilabel(app.panel_segmentation);
            app.PropertythresholdEditFieldLabel.Text = 'Property threshold';
            app.PropertythresholdEditField = uieditfield(app.panel_segmentation, 'numeric');
            app.button_delCurrentPop_2 = uibutton(app.panel_segmentation, 'push');
            app.button_delCurrentPop_2.ButtonPushedFcn = createCallbackFcn(app, @button_delCurrentPop_2ButtonPushed, true);
            app.button_delCurrentPop_2.Text = 'Merge current popluation with';
            app.DropDown = uidropdown(app.panel_segmentation);
            app.DropDown.Items = {'1', '2', '3', '4'};
            app.DropDown.Value = '1';
            app.button_copytable = uibutton(app.figure1, 'push');
            app.button_copytable.ButtonPushedFcn = createCallbackFcn(app, @button_copytable_Callback, true);
            app.button_copytable.Text = 'Copy table to clipboard';
            app.DropDown_TrackNbr = uidropdown(app.figure1);
            app.DropDown_TrackNbr.Items = {'Track #'};
            app.DropDown_TrackNbr.Value = 'Track #';
            app.DropDown_PopulationNbr = uidropdown(app.figure1);
            app.DropDown_PopulationNbr.Items = {'Population #'};
            app.DropDown_PopulationNbr.ValueChangedFcn = createCallbackFcn(app, @DropDown_PopulationNbr_Callback, true);
            app.DropDown_PopulationNbr.Value = 'Population #';
            app.table1 = uitable(app.figure1);
            app.table1.ColumnName = {'Track ID'; '1st property'};
            app.table1.ColumnEditable = [false false];
            app.DropDown_loadDest = uidropdown(app.figure1);
            app.DropDown_loadDest.Items = {'Select diffusion estimator'};
            app.DropDown_loadDest.ValueChangedFcn = createCallbackFcn(app, @DropDown_loadDest_Callback, true);
            app.DropDown_loadDest.Value = 'Select diffusion estimator';
            app.DropDown_TrackProp1 = uidropdown(app.figure1);
            app.DropDown_TrackProp1.Items = {'Track property 1'};
            app.DropDown_TrackProp1.ValueChangedFcn = createCallbackFcn(app, @DropDown_TrackProp1_Callback, true);
            app.DropDown_TrackProp1.Value = 'Track property 1';
            app.DropDown_TrackProp2 = uidropdown(app.figure1);
            app.DropDown_TrackProp2.Items = {'Track property 2'};
            app.DropDown_TrackProp2.ValueChangedFcn = createCallbackFcn(app, @DropDown_TrackProp2_Callback, true);
            app.DropDown_TrackProp2.Value = 'Track property 2';
            app.button_DestOptions = uibutton(app.figure1, 'push');
            app.button_DestOptions.ButtonPushedFcn = createCallbackFcn(app, @button_DestOptions_Callback, true);
            app.button_DestOptions.Text = 'Diffusion estimator options';
            app.button_setcurrentDesttoall = uibutton(app.figure1, 'push');
            app.button_setcurrentDesttoall.ButtonPushedFcn = createCallbackFcn(app, @button_setcurrentDesttoall_Callback, true);
            app.button_setcurrentDesttoall.Text = 'Set current estimator to all populations';
            app.button_computediffusion = uibutton(app.figure1, 'push');
            app.button_computediffusion.ButtonPushedFcn = createCallbackFcn(app, @button_computediffusion_Callback, true);
            app.button_computediffusion.Text = 'Compute diffusion ';
            app.checkbox_computeDallpop = uicheckbox(app.figure1);
            app.checkbox_computeDallpop.Text = 'all pop.';
            app.checkbox_computeDallpop.Value = true;
            app.DropDown_Dmodel = uidropdown(app.figure1);
            app.DropDown_Dmodel.Items = {'Select diffusion model'};
            app.DropDown_Dmodel.ValueChangedFcn = createCallbackFcn(app, @DropDown_Dmodel_Callback, true);
            app.DropDown_Dmodel.Value = 'Select diffusion model';
            app.button_reportDest = uibutton(app.figure1, 'push');
            app.button_reportDest.ButtonPushedFcn = createCallbackFcn(app, @button_reportDest_Callback, true);
            app.button_reportDest.Text = 'Report diffusion population';
            app.button_DestPlots = uibutton(app.figure1, 'push');
            app.button_DestPlots.ButtonPushedFcn = createCallbackFcn(app, @button_DestPlots_Callback, true);
            app.button_DestPlots.Text = 'Diffusion-estimator specific plots';
            app.button_plotaptialmaphexgrid = uibutton(app.figure1, 'push');
            app.button_plotaptialmaphexgrid.ButtonPushedFcn = createCallbackFcn(app, @button_plotaptialmaphexgridButtonPushed, true);
            app.button_plotaptialmaphexgrid.Enable = 'off';
            app.button_plotaptialmaphexgrid.Visible = 'off';
            app.button_plotaptialmaphexgrid.Text = 'Plot spatial map 1st property (hex grid)';
            app.LogarithmicCheckBox = uicheckbox(app.figure1);
            app.LogarithmicCheckBox.Enable = 'off';
            app.LogarithmicCheckBox.Visible = 'off';
            app.LogarithmicCheckBox.Text = 'Logarithmic';
            app.BinsizenmEditFieldLabel = uilabel(app.figure1);
            app.BinsizenmEditFieldLabel.Enable = 'off';
            app.BinsizenmEditFieldLabel.Visible = 'off';
            app.BinsizenmEditFieldLabel.Text = 'Bin size (nm)';
            app.BinsizenmEditField = uieditfield(app.figure1, 'numeric');
            app.BinsizenmEditField.LowerLimitInclusive = 'off';
            app.BinsizenmEditField.UpperLimitInclusive = 'off';
            app.BinsizenmEditField.Limits = [0 Inf];
            app.BinsizenmEditField.Enable = 'off';
            app.BinsizenmEditField.Visible = 'off';
            app.BinsizenmEditField.Value = 500;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffusionLab_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)DiffusionLab_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end