classdef ExportFigures_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        button_exportall               matlab.ui.control.Button
        button_exportcurrentfig        matlab.ui.control.Button
        DropDown_exportext             matlab.ui.control.DropDown
        DropDown_paperunits            matlab.ui.control.DropDown
        checkbox_exporttitle           matlab.ui.control.CheckBox
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        ProjectsavenameEditFieldLabel  matlab.ui.control.Label
        ProjectsavenameEditField       matlab.ui.control.EditField
        CloseButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent % parent object with field parent.printFig with contains a 'printFig' object
        debugFlag = false; % debug mode
    end
    
    methods (Access = private)
        
        function errorBox(app, err)
            % if there is an error invoked, we output it in a dialog box
            if app.debugFlag
                errordlg(getReport(err,'extended','hyperlinks','off'),'Error');
            else
                errordlg(getReport(err,'basic','hyperlinks','off'),'Error');
            end
        end
        
    end

    
    methods
        
        function set.debugFlag(app,value)
            validateattributes(value,{'logical'},{'scalar'})
            app.debugFlag = value;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent, debug)
            try
                app.parent = parent;
                if nargin > 2
                    app.debugFlag = logical(debug);
                end
                
                % set the values in printFig to GUI
                if iscell(app.parent.printFig.plotExt)
                    % take first entry
                    app.DropDown_exportext.Value = app.parent.printFig.plotExt{1};
                else
                    app.DropDown_exportext.Value = app.parent.printFig.plotExt;
                end
                app.DropDown_paperunits.Value = app.parent.printFig.paperUnits;
                app.WidthEditField.Value = app.parent.printFig.paperPosition(3);
                app.HeightEditField.Value = app.parent.printFig.paperPosition(4);
                app.ProjectsavenameEditField.Value = app.parent.printFig.projectname;
                app.DropDown_paperunits.Value = app.parent.printFig.paperUnits;
                
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_exportext
        function DropDown_exportextValueChanged(app, event)
            try
                app.parent.printFig.plotExt = app.DropDown_exportext.Value;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: DropDown_paperunits
        function DropDown_paperunitsValueChanged(app, event)
            try
                app.parent.printFig.paperUnits = app.DropDown_paperunits.Value;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            try
                app.parent.printFig.paperPosition(3) = app.WidthEditField.Value;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            try
                app.parent.printFig.paperPosition(4) = app.HeightEditField.Value;
            catch err
                app.errorBox(err)
            end
        end

        % Value changed function: ProjectsavenameEditField
        function ProjectsavenameEditFieldValueChanged(app, event)
            try
                app.parent.printFig.projectname = app.ProjectsavenameEditField.Value;
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_exportall
        function button_exportallButtonPushed(app, event)
            try
                app.parent.printFig.overwriteFlag = false; % ensure expected behaviour, i.e. no overwriting of empty titles
                figHandles = findobj('Type','figure');
                n_openFigs = size(figHandles);
                jj = 1;
                for ii=1:n_openFigs
                    if ~strcmp(figHandles(ii).Name,mfilename)
                        if app.checkbox_exporttitle.Value
                            savename = get(get(gca,'Title'),'String');
                            if isempty(savename)
                                savename = ['empty_title_' num2str(jj)];
                            end
                        else
                            savename = '';
                        end
                        app.parent.printFig.print(savename,false);
                    end
                end
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: button_exportcurrentfig
        function button_exportcurrentfigButtonPushed(app, event)
            try
                app.parent.printFig.overwriteFlag = false; % ensure expected behaviour, i.e. no overwriting of empty titles
                figHandles = findobj('Type','figure');
                if app.checkbox_exporttitle.Value
                    savename = get(get(gca,'Title'),'String');
                    if isempty(savename)
                        savename = 'empty_title';
                    end
                else
                    savename = '';
                end
                ii = 1;
                if strcmp(figHandles(ii).Name,mfilename)
                     ii = 2;
                end
                figure(figHandles(ii));
                app.parent.printFig.print(savename,false);
            catch err
                app.errorBox(err)
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.button_exportall = uibutton(app.UIFigure, 'push');
            app.button_exportall.ButtonPushedFcn = createCallbackFcn(app, @button_exportallButtonPushed, true);
            app.button_exportall.Text = 'Export all open figures';
            app.button_exportcurrentfig = uibutton(app.UIFigure, 'push');
            app.button_exportcurrentfig.ButtonPushedFcn = createCallbackFcn(app, @button_exportcurrentfigButtonPushed, true);
            app.button_exportcurrentfig.Text = 'Export current open figure';
            app.DropDown_exportext = uidropdown(app.UIFigure);
            app.DropDown_exportext.Items = {'jpeg', 'png', 'tiff', 'pdf', 'eps', 'meta', 'svg', 'ps', 'epsc', 'eps2', 'epsc2', 'psc', 'ps2', 'psc2', 'tiffn', 'meta', 'bmpmono', 'bmp', 'bmp16m', 'bmp256', 'hdf', 'pbm', 'pbmraw', 'pcxmono', 'pcx24b', 'pcx256', 'pcx16', 'pgm', 'pgmraw', 'ppm', 'ppmraw'};
            app.DropDown_exportext.ItemsData = {'-djpeg', '-dpng', '-dtiff', '-dpdf', '-deps', '-dmeta', '-dsvg', '-dps', '-depsc', '-deps2', '-depsc2', '-dpsc', '-dps2', '-dpsc2', '-dtiffn', '-dmeta', '-dbmpmono', '-dbmp', '-dbmp16m', '-dbmp256', '-dhdf', '-dpbm', '-dpbmraw', '-dpcxmono', '-dpcx24b', '-dpcx256', '-dpcx16', '-dpgm', '-dpgmraw', '-dppm', '-dppmraw'};
            app.DropDown_exportext.ValueChangedFcn = createCallbackFcn(app, @DropDown_exportextValueChanged, true);
            app.DropDown_exportext.Value = '-djpeg';
            app.DropDown_paperunits = uidropdown(app.UIFigure);
            app.DropDown_paperunits.Items = {'centimeters', 'inches', 'points', 'pixels'};
            app.DropDown_paperunits.ValueChangedFcn = createCallbackFcn(app, @DropDown_paperunitsValueChanged, true);
            app.DropDown_paperunits.Value = 'centimeters';
            app.checkbox_exporttitle = uicheckbox(app.UIFigure);
            app.checkbox_exporttitle.Text = 'Append figure title';
            app.checkbox_exporttitle.Value = true;
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.UpperLimitInclusive = 'off';
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 10;
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.UpperLimitInclusive = 'off';
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 8;
            app.ProjectsavenameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectsavenameEditFieldLabel.Text = 'Project save name';
            app.ProjectsavenameEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectsavenameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectsavenameEditFieldValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportFigures_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end